classdef H1Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionsLabel                    matlab.ui.control.Label
        colorspaceDropDownLabel         matlab.ui.control.Label
        wbccolorspaceDropDown           matlab.ui.control.DropDown
        colorlayerDropDownLabel         matlab.ui.control.Label
        wbccolorlayerDropDown           matlab.ui.control.DropDown
        imageresizeratioEditFieldLabel  matlab.ui.control.Label
        imageresizeratioEditField       matlab.ui.control.NumericEditField
        binarizationmethodDropDownLabel  matlab.ui.control.Label
        wbcbinarizationmethodDropDown   matlab.ui.control.DropDown
        binarizationsensitivityEditFieldLabel  matlab.ui.control.Label
        wbcbinarizationsensitivityEditField  matlab.ui.control.NumericEditField
        RBCminimumsizeEditFieldLabel    matlab.ui.control.Label
        RBCminimumsizeEditField         matlab.ui.control.NumericEditField
        BWopeningvalueEditFieldLabel    matlab.ui.control.Label
        wbcBWopeningvalueEditField      matlab.ui.control.NumericEditField
        BWclosingvalueEditFieldLabel    matlab.ui.control.Label
        wbcBWclosingvalueEditField      matlab.ui.control.NumericEditField
        saveButton                      matlab.ui.control.Button
        defaultsButton                  matlab.ui.control.Button
        WBCDetectionLabel               matlab.ui.control.Label
        colorspaceDropDown_2Label       matlab.ui.control.Label
        rbccolorspaceDropDown           matlab.ui.control.DropDown
        colorlayerDropDown_2Label       matlab.ui.control.Label
        rbccolorlayerDropDown           matlab.ui.control.DropDown
        binarizationmethodDropDown_2Label  matlab.ui.control.Label
        rbcbinarizationmethodDropDown   matlab.ui.control.DropDown
        binarizationsensitivityEditField_2Label  matlab.ui.control.Label
        rbcbinarizationsensitivityEditField  matlab.ui.control.NumericEditField
        BWopeningvalueEditField_2Label  matlab.ui.control.Label
        rbcBWopeningvalueEditField      matlab.ui.control.NumericEditField
        BWclosingvalueEditField_2Label  matlab.ui.control.Label
        rbcBWclosingvalueEditField      matlab.ui.control.NumericEditField
        RBCDetectionLabel               matlab.ui.control.Label
        loadButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        H1mainApp % a property to store main app
        data = struct('imageResizeRatio', 0.5,...
            'wbccolorSpace','hsv',...
            'wbccolorLayer', 'layer 2',...
            'wbcbinarizationMethod', 'manual',...
            'wbcbinarizationSensitivity', 0.98,...
            'wbcBWopeningValue' , 3,...
            'wbcBWclosingValue' , 2,...
            'rbccolorSpace','hsv',...
            'rbccolorLayer', 'layer 2',...
            'rbcbinarizationMethod', 'adaptive',...
            'rbcbinarizationSensitivity', 0.9,...
            'rbcBWopeningValue' , 5,...
            'rbcBWclosingValue' , 1,...
            'RBCminimumSize' , 2000); % Description
    end
    
    methods (Access = private)
        
        function getvalues(app)
            app.data.imageResizeRatio = app.imageresizeratioEditField.Value;
            app.data.wbccolorSpace = app.wbccolorspaceDropDown.Value;
            app.data.wbccolorLayer = app.wbccolorlayerDropDown.Value;
            app.data.wbcbinarizationMethod = app.wbcbinarizationmethodDropDown.Value;
            app.data.wbcbinarizationSensitivity = app.wbcbinarizationsensitivityEditField.Value;
            app.data.wbcBWopeningValue = app.wbcBWopeningvalueEditField.Value;
            app.data.wbcBWclosingValue = app.wbcBWclosingvalueEditField.Value;
            app.data.rbccolorSpace = app.rbccolorspaceDropDown.Value;
            app.data.rbccolorLayer = app.rbccolorlayerDropDown.Value;
            app.data.rbcbinarizationMethod = app.rbcbinarizationmethodDropDown.Value;
            app.data.rbcbinarizationSensitivity = app.rbcbinarizationsensitivityEditField.Value;
            app.data.rbcBWopeningValue = app.rbcBWopeningvalueEditField.Value;
            app.data.rbcBWclosingValue = app.rbcBWclosingvalueEditField.Value;
            app.data.RBCminimumSize = app.RBCminimumsizeEditField.Value;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, optionsValues)
            app.H1mainApp = mainApp; % Store main app object
            app.imageresizeratioEditField.Value = optionsValues.imageResizeRatio;
            app.wbccolorspaceDropDown.Value = optionsValues.wbccolorSpace;
            app.wbccolorlayerDropDown.Value = optionsValues.wbccolorLayer;
            app.wbcbinarizationmethodDropDown.Value = optionsValues.wbcbinarizationMethod;
            app.wbcbinarizationsensitivityEditField.Value = optionsValues.wbcbinarizationSensitivity;
            app.wbcBWopeningvalueEditField.Value = optionsValues.wbcBWopeningValue;
            app.wbcBWclosingvalueEditField.Value = optionsValues.wbcBWclosingValue;
            app.rbccolorspaceDropDown.Value = optionsValues.rbccolorSpace;
            app.rbccolorlayerDropDown.Value = optionsValues.rbccolorLayer;
            app.rbcbinarizationmethodDropDown.Value = optionsValues.rbcbinarizationMethod;
            app.rbcbinarizationsensitivityEditField.Value = optionsValues.rbcbinarizationSensitivity;
            app.rbcBWopeningvalueEditField.Value = optionsValues.rbcBWopeningValue;
            app.rbcBWclosingvalueEditField.Value = optionsValues.rbcBWclosingValue;
            app.RBCminimumsizeEditField.Value = optionsValues.RBCminimumSize;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.getvalues();
            % Call main app's public function
            getoptionsvalues(app.H1mainApp, app.data);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.H1mainApp.optionsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: defaultsButton
        function defaultsButtonPushed(app, event)
            app.imageresizeratioEditField.Value = app.data.imageResizeRatio;
            app.wbccolorspaceDropDown.Value = app.data.wbccolorSpace;
            app.wbccolorlayerDropDown.Value = app.data.wbccolorLayer;
            app.wbcbinarizationmethodDropDown.Value = app.data.wbcbinarizationMethod;
            app.wbcbinarizationsensitivityEditField.Value = app.data.wbcbinarizationSensitivity;
            app.wbcBWopeningvalueEditField.Value = app.data.wbcBWopeningValue;
            app.wbcBWclosingvalueEditField.Value = app.data.wbcBWclosingValue;
            app.rbccolorspaceDropDown.Value = app.data.rbccolorSpace;
            app.rbccolorlayerDropDown.Value = app.data.rbccolorLayer;
            app.rbcbinarizationmethodDropDown.Value = app.data.rbcbinarizationMethod;
            app.rbcbinarizationsensitivityEditField.Value = app.data.rbcbinarizationSensitivity;
            app.rbcBWopeningvalueEditField.Value = app.data.rbcBWopeningValue;
            app.rbcBWclosingvalueEditField.Value = app.data.rbcBWclosingValue;
            app.RBCminimumsizeEditField.Value = app.data.RBCminimumSize;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Text = 'Options';
            app.colorspaceDropDownLabel = uilabel(app.UIFigure);
            app.colorspaceDropDownLabel.Text = 'color space';
            app.wbccolorspaceDropDown = uidropdown(app.UIFigure);
            app.wbccolorspaceDropDown.Items = {'rgb', 'hsv', 'cmyk'};
            app.wbccolorspaceDropDown.Value = 'hsv';
            app.colorlayerDropDownLabel = uilabel(app.UIFigure);
            app.colorlayerDropDownLabel.Text = 'color layer';
            app.wbccolorlayerDropDown = uidropdown(app.UIFigure);
            app.wbccolorlayerDropDown.Items = {'layer 1', 'layer 2', 'layer 3', 'layer 4'};
            app.wbccolorlayerDropDown.Value = 'layer 2';
            app.imageresizeratioEditFieldLabel = uilabel(app.UIFigure);
            app.imageresizeratioEditFieldLabel.Text = 'image resize ratio';
            app.imageresizeratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageresizeratioEditField.Limits = [0.1 2];
            app.imageresizeratioEditField.Value = 0.5;
            app.binarizationmethodDropDownLabel = uilabel(app.UIFigure);
            app.binarizationmethodDropDownLabel.Text = 'binarization method';
            app.wbcbinarizationmethodDropDown = uidropdown(app.UIFigure);
            app.wbcbinarizationmethodDropDown.Items = {'otsu', 'graythresh', 'adaptive', 'manual'};
            app.wbcbinarizationmethodDropDown.Value = 'manual';
            app.binarizationsensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.binarizationsensitivityEditFieldLabel.Text = 'binarization sensitivity';
            app.wbcbinarizationsensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.wbcbinarizationsensitivityEditField.Limits = [0.1 1];
            app.wbcbinarizationsensitivityEditField.Value = 0.98;
            app.RBCminimumsizeEditFieldLabel = uilabel(app.UIFigure);
            app.RBCminimumsizeEditFieldLabel.Text = 'RBC minimum size';
            app.RBCminimumsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RBCminimumsizeEditField.Limits = [10 2000];
            app.RBCminimumsizeEditField.ValueDisplayFormat = '%.0f';
            app.RBCminimumsizeEditField.Value = 2000;
            app.BWopeningvalueEditFieldLabel = uilabel(app.UIFigure);
            app.BWopeningvalueEditFieldLabel.Text = 'BW opening value';
            app.wbcBWopeningvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.wbcBWopeningvalueEditField.Limits = [1 10];
            app.wbcBWopeningvalueEditField.ValueDisplayFormat = '%.0f';
            app.wbcBWopeningvalueEditField.Value = 3;
            app.BWclosingvalueEditFieldLabel = uilabel(app.UIFigure);
            app.BWclosingvalueEditFieldLabel.Text = 'BW closing value';
            app.wbcBWclosingvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.wbcBWclosingvalueEditField.Limits = [1 10];
            app.wbcBWclosingvalueEditField.ValueDisplayFormat = '%.0f';
            app.wbcBWclosingvalueEditField.Value = 2;
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.defaultsButton = uibutton(app.UIFigure, 'push');
            app.defaultsButton.ButtonPushedFcn = createCallbackFcn(app, @defaultsButtonPushed, true);
            app.defaultsButton.Text = 'defaults';
            app.WBCDetectionLabel = uilabel(app.UIFigure);
            app.WBCDetectionLabel.Text = 'WBC Detection';
            app.colorspaceDropDown_2Label = uilabel(app.UIFigure);
            app.colorspaceDropDown_2Label.Text = 'color space';
            app.rbccolorspaceDropDown = uidropdown(app.UIFigure);
            app.rbccolorspaceDropDown.Items = {'rgb', 'hsv', 'cmyk'};
            app.rbccolorspaceDropDown.Value = 'hsv';
            app.colorlayerDropDown_2Label = uilabel(app.UIFigure);
            app.colorlayerDropDown_2Label.Text = 'color layer';
            app.rbccolorlayerDropDown = uidropdown(app.UIFigure);
            app.rbccolorlayerDropDown.Items = {'layer 1', 'layer 2', 'layer 3', 'layer 4'};
            app.rbccolorlayerDropDown.Value = 'layer 2';
            app.binarizationmethodDropDown_2Label = uilabel(app.UIFigure);
            app.binarizationmethodDropDown_2Label.Text = 'binarization method';
            app.rbcbinarizationmethodDropDown = uidropdown(app.UIFigure);
            app.rbcbinarizationmethodDropDown.Items = {'otsu', 'graythresh', 'adaptive', 'manual'};
            app.rbcbinarizationmethodDropDown.Value = 'manual';
            app.binarizationsensitivityEditField_2Label = uilabel(app.UIFigure);
            app.binarizationsensitivityEditField_2Label.Text = 'binarization sensitivity';
            app.rbcbinarizationsensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.rbcbinarizationsensitivityEditField.Limits = [0.1 1];
            app.rbcbinarizationsensitivityEditField.Value = 0.98;
            app.BWopeningvalueEditField_2Label = uilabel(app.UIFigure);
            app.BWopeningvalueEditField_2Label.Text = 'BW opening value';
            app.rbcBWopeningvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.rbcBWopeningvalueEditField.Limits = [1 10];
            app.rbcBWopeningvalueEditField.ValueDisplayFormat = '%.0f';
            app.rbcBWopeningvalueEditField.Value = 3;
            app.BWclosingvalueEditField_2Label = uilabel(app.UIFigure);
            app.BWclosingvalueEditField_2Label.Text = 'BW closing value';
            app.rbcBWclosingvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.rbcBWclosingvalueEditField.Limits = [1 10];
            app.rbcBWclosingvalueEditField.ValueDisplayFormat = '%.0f';
            app.rbcBWclosingvalueEditField.Value = 2;
            app.RBCDetectionLabel = uilabel(app.UIFigure);
            app.RBCDetectionLabel.Text = 'RBC Detection';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.Text = 'load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = H1Options(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end