classdef H1_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        optionsButton                 matlab.ui.control.Button
        ProgressGauge                 matlab.ui.control.Gauge
        ProgressGaugeLabel            matlab.ui.control.Label
        CellQtDropDown                matlab.ui.control.DropDown
        CellQtDropDownLabel           matlab.ui.control.Label
        MessageBox                    matlab.ui.control.TextArea
        messageboxLabel               matlab.ui.control.Label
        NextRegion                    matlab.ui.control.Button
        PrevRegion                    matlab.ui.control.Button
        SaveProgressButton            matlab.ui.control.Button
        LoadProgressButton            matlab.ui.control.Button
        RegionIDLabel                 matlab.ui.control.Label
        ImageNameLabel                matlab.ui.control.Label
        HelpButton                    matlab.ui.control.Button
        OperatorNameEditField         matlab.ui.control.EditField
        OperatorNameEditFieldLabel    matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        OperatorDataCollectorLabel    matlab.ui.control.Label
        StartSegmentationSwitch       matlab.ui.control.ToggleSwitch
        StartSegmentationSwitchLabel  matlab.ui.control.Label
        StatisticsPanel               matlab.ui.container.Panel
        UnknownRegionValueLabel       matlab.ui.control.Label
        AbnormalRBCValueLabel         matlab.ui.control.Label
        NormalRBCValueLabel           matlab.ui.control.Label
        DetectedRegionValueLabel      matlab.ui.control.Label
        UnknownRegionsLabel           matlab.ui.control.Label
        AbnormalRBCsLabel             matlab.ui.control.Label
        NormalRBCsLabel               matlab.ui.control.Label
        DetectedRegionsLabel          matlab.ui.control.Label
        LoadImageButton               matlab.ui.control.Button
        BloodSmearImageLabel          matlab.ui.control.Label
        SegmentedRegionLabel          matlab.ui.control.Label
        CellstateButtonGroup          matlab.ui.container.ButtonGroup
        UnknownButton                 matlab.ui.control.ToggleButton
        AbnormalRBCButton             matlab.ui.control.ToggleButton
        NormalRBCButton               matlab.ui.control.ToggleButton
        Abnormalityknob               matlab.ui.control.DiscreteKnob
        knobKnobLabel                 matlab.ui.control.Label
        SmearUIAxes                   matlab.ui.control.UIAxes
        CellUIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %test edit
        operatorAnalysisResults = struct('operatorName',0,...       %is the main Variable and stores all final results
            'operatorNumObjects',0,...
            'operatorNumRBCs',0,...
            'operatorNumNormalRBCs',0,...
            'operatorNumAbnormalRBCs',0,...
            'operatorNumFragments',0,...
            'softwareNumObjects',0,...
            'softwareNumFragments', 0,...
            'softwareNumRBCs',0,...
            'lastAnalysedRegionRow',0,...%last analysed region row number
            'region',struct('regionID',uint16([00, 00]),...
            'position',[],...
            'mask',0,...
            'cell',0,...
            'cellRGB',0,...
            'isRBC',true,...
            'GLCM',[],...
            'operatorAnalysis', 1,...
            'operatorCellQt', 1)); %this struct does not include fragment data % Description
        operatorName = 'unknown'; % Description
        numOfRegions = 0; % Description
        progressFileName = 'unknown'; % Description
        currentRegionRowNumber = 2; %first region data in 'operatorAnalysisResults' is in row 2 and other in higher number rows
        currentRegion = struct('regionID',uint16([00, 00]) ,'currentCellMask',0) % Save current region that is processed to be use it in continuing a saved progress.
        targetImage = struct('filePath','0','rgbImage', [], 'mask', [],'oneDImage', [], 'connectedComponents',[]);
        imResizeRatio = 0.5;
%         colorSpace = 'hsv';
%         colorLayer = 2;
%         binarizationMethod = 'manual';
%         binarizationSensitivity = 0.98;
%         BWopeningValue = 3;
%         BWclosingValue = 2;
%         RBCminimumSize = 200;
        optionsvalues = struct('imageResizeRatio', 0.5,...
            'wbccolorSpace','hsv',...
            'wbccolorLayer', 'layer 2',...
            'wbcbinarizationMethod', 'manual',...
            'wbcbinarizationSensitivity', 0.98,...
            'wbcBWopeningValue' , 3,...
            'wbcBWclosingValue' , 2,...
            'rbccolorSpace','hsv',...
            'rbccolorLayer', 'layer 2',...
            'rbcbinarizationMethod', 'adaptive',...
            'rbcbinarizationSensitivity', 0.9,...
            'rbcBWopeningValue' , 5,...
            'rbcBWclosingValue' , 1,...
            'RBCminimumSize' , 2000);
    end
    
    properties (Access = private)
        
        %here
        H1OptionsDialogApp % a prperty to store the options dialog box app
        operatorButtonSelection = struct('cellState',1,...
            'abnormality',21,...
            'cellQt',1);
        operatorRegionType = struct('normal',1,...
            'abnormal', struct('target',21,...
            'spherocyte',22,...
            'hyper',23, 'hypo',24),...
            'unknown',3); %Operator selections values
        singleRegionAnalysisResults = struct( 'numObjects',0,...      any subregion
            'numRBCs',0,...         any subregion that is larger than 'rbcAreaThreshold'
            'numFragments',0,...    any sub-region (object) that is smaller than 'rbcAreaThreshold'
            'fragmentIDs',[],...    ID of fregment including the main region ID
            'region',struct('regionID',uint16([00, 00]),...
            'position',[],...
            'mask',0,... main single region mask is used as defualt value
            'cell',0,... main single region masked image is used as default value
            'cellRGB',0,...
            'isRBC',true,...
            'GLCM', [],...
            'operatorAnalysis', 1,...
            'operatorCellQt', 1));
        loadFilePath = '';
        loadedFile = [];
        progressIsSaved = false; % Description
        loadProgressDone = false;
        userinterfaceCounter = 0; %a counter used for UI refresh
        operatorEditAnalysed = struct(...
            'cellStateEditFlag', false,...
            'cellQtEditFlag', false,...
            'cellAbnormalityEditFlag', false,...
            'cellStateOld', 1,...% 1='normal' , 2='abnormal' , 3='unknown'
            'cellStateNew', 1,...% 1='normal' , 2='abnormal' , 3='unknown'
            'cellQtOld', 1,...% 1 2 3 4 5 6 7
            'cellQtNew', 0,...% 1 2 3 4 5 6 7
            'cellAbnormalityOld', 21,...%'target'=21 , 'spherocyte'=22 ,'hyper'=23 ,'hypo'=24
            'cellAbnormalityNew', 21);%'target'=21 , 'spherocyte'=22 ,'hyper'=23 ,'hypo'=24
        %         operatorSelectionCellStateEditFlag = false;
        %         operatorSelectionCellQtEditFlag = false;
        %         operatorSelectionCellAbnormalityEditFlag = false;
        
        %targetImage = struct('filePath','0','rgbImage', [], 'mask', [],'oneDImage', [], 'connectedComponents',[]);
    end
    
    methods (Access = private)
        function updatesmearimage(app, imageFile, fileName)
            %  opens the selected image in " Blood Smear Image" box
            applog(app,"H1: ""updatesmearimage"" function called");
            %             disp('H1: "updatesmearimage" function called')
            try
                im = imread(imageFile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            % Check if the image is Truecolor.
            switch size(im,3)
                case 3
                    % Display the truecolor image
                    app.targetImage.rgbImage = im;
                    imshow(im,'parent',app.SmearUIAxes);
                    app.ImageNameLabel.Text = fileName;
                    app.ImageNameLabel.Tooltip = {imageFile};
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be truecolor.', 'Image Error', 'Icon','error', 'Modal',true);
                    app.Lamp.Color = 'red';
                    app.Lamp.Enable = 'on';
                    return;
            end
            app.Lamp.Color = 'yellow';
            app.Lamp.Enable = 'off';
            
        end
        
        function analysproperties(app,offsetNumber, offsetType)
            %calculates GLCM
            %inputs: GLCM offsetNumber, GLCM offsetType, app.targetImage, 
            % app.singleregionsegmentor() function is used here.
            %output: app.singleRegionAnalysisResults, app.operatorAnalysisResults
            disptext = ['H1: "analysproperties" function called. Number of objects > ',num2str(app.targetImage.connectedComponents.NumObjects)];
            applog(app,string(disptext));
            %             disp(disptext)
            if ~exist('offsetNumber','var')
                offsetNumber = 40; %default value for rbcAreaThreshold
            end
            switch offsetType
                case 'omni'
                    glcmOffset = [0 offsetNumber;-offsetNumber offsetNumber;-offsetNumber 0;-offsetNumber -offsetNumber];
                    applog(app,"H1: analysproperties: offsetType is omni");
                    %                     disp('H1: analysproperties: offsetType is omni')
                case 'offset0'
                    glcmOffset = [zeros(offsetNumber,1) (1:offsetNumber)'];
                    applog(app,"H1: analysproperties: offsetType is offset0");
                    %                     disp('H1: analysproperties: offsetType is offset0')
                case 'offset45'
                    glcmOffset = [-(1:1:offsetNumber)' (1:1:offsetNumber)'];
                    applog(app,"H1: analysproperties: offsetType is offset45");
                    %                     disp('H1: analysproperties: offsetType is offset45')
                case 'offset90'
                    glcmOffset = [-(1:1:offsetNumber)' zeros(offsetNumber,1)];
                    applog(app,"H1: analysproperties: offsetType is offset90");
                    %                     disp('H1: analysproperties: offsetType is offset90')
                case 'offset135'
                    glcmOffset = [-(1:1:offsetNumber)' -(1:1:offsetNumber)'];
                    applog(app,"H1: analysproperties: offsetType is offset135");
                    %                     disp('H1: analysproperties: offsetType is offset135')
                otherwise
                    glcmOffset = [-(1:offsetNumber)' (1:1:offsetNumber)']; % if the offset type is not defined correctly, offset45 is considered
                    applog(app,"H1>analysproperties: offsetType is unknown (offset45 selected)");
                    %                     disp('H1>analysproperties: offsetType is unknown (offset45 selected)')
            end
            gaugeStep = 100/app.targetImage.connectedComponents.NumObjects;
            
            for regionNum = 1:app.targetImage.connectedComponents.NumObjects   %from now on regionNum will be cell or cell cluster ID.
                %for cells exctracted from cell clusters a second ID will be generated.
                
                app.currentRegion.currentCellMask = false(size(app.targetImage.mask)); % building a mask the same size as image
                app.currentRegion.currentCellMask(app.targetImage.connectedComponents.PixelIdxList{regionNum}) = true;% building single region mask
                app.numOfRegions = app.targetImage.connectedComponents.NumObjects;
                %%%%%%%%%%%%%%% imshow(app.targetImage.oneDImage,'parent',app.CellUIAxes);
                
                app.currentRegion.regionID(1) = regionNum;
                app.singleRegionAnalysisResults = app.singleregionsegmentor(app.targetImage.rgbImage, app.targetImage.oneDImage, app.currentRegion.currentCellMask, app.currentRegion.regionID(1));
                disptext = ['H1>analysproperties: "singleregionsegmentor" function called for main region no ',num2str(regionNum)];
                applog(app,string(disptext));
                %                 disp(disptext)
                %             imshow(app.currentRegion.currentCellMask,'parent', app.CellUIAxes);
                %             sjdhg
                %test = app.singleRegionAnalysisResults;
                %save('log.mat', '-struct','test','-mat');
                %calculating the GLCM
                %start test
                %                 app.singleRegionAnalysisResults.numObjects
                %end test
                for regioni = 1 : app.singleRegionAnalysisResults.numObjects
                    disptext = ['H1>analysproperties: Is sub-region ',num2str(regioni),' that "singleregionsegmentor" function detected, an RBC?_region no: ',num2str(regionNum),' & singleRegionAnalysisResult numObjects:', num2str(app.singleRegionAnalysisResults.numObjects)];
                    applog(app,string(disptext));
                    %                     disp(disptext)
                    app.currentRegion.regionID(2) = regioni;
                    %app.singleRegionAnalysisResults.region
                    %save('log2.mat','glcmOffset','-mat');
                    if app.singleRegionAnalysisResults.region(regioni).isRBC
                        glcms = graycomatrix(app.singleRegionAnalysisResults.region(regioni).cell, 'offset', glcmOffset);
                        glcmStats = graycoprops(glcms,'all');
                        disptext = ['H1>analysproperties: GLCM calculated for sub-region ',num2str(regioni),'_region no: ',num2str(regionNum)];
                        applog(app,string(disptext));
                        %                         disp(disptext)
                        app.singleRegionAnalysisResults.region(regioni).GLCM = glcmStats;
                    end
                end
                app.operatorAnalysisResults.region = [app.operatorAnalysisResults.region, app.singleRegionAnalysisResults.region];
                app.operatorAnalysisResults.softwareNumObjects = app.operatorAnalysisResults.softwareNumObjects + app.singleRegionAnalysisResults.numObjects;
                app.operatorAnalysisResults.softwareNumRBCs = app.operatorAnalysisResults.softwareNumRBCs + app.singleRegionAnalysisResults.numRBCs;
                app.operatorAnalysisResults.softwareNumFargments = app.operatorAnalysisResults.softwareNumFragments + app.singleRegionAnalysisResults.numFragments;
                %app.operatorAnalysisResults.region = [app.operatorAnalysisResults.region, app.singleRegionAnalysisResults.region];
                app.Lamp.Enable = 'on';
                if app.userinterfaceCounter <= 3
                    app.Lamp.Color = 'green';
                    app.userinterfaceCounter = app.userinterfaceCounter + 1;
                elseif app.userinterfaceCounter > 3
                    app.Lamp.Color = 'yellow';
                    app.userinterfaceCounter = app.userinterfaceCounter + 1;
                end
                app.ProgressGauge.Value = regionNum*gaugeStep;
                drawnow;
                if app.userinterfaceCounter == 7
                    app.userinterfaceCounter = 0;
                end
                
                
            end
            applog(app,"H1: ""analysproperties"" function execution finished.");
            %             disp('H1: "analysproperties" function execution finished.')
        end
        
        function fileName = saveworkspace(app)
            %saves workspace variables for next step or next sesion.
            applog(app,"H1: ""saveworkspace"" function called.");
            %             disp('H1: "saveworkspace" function called.')
            if isequal(app.progressFileName, 'unknown')
                app.progressFileName = strcat('H1_',app.operatorName,'_', app.ImageNameLabel.Text);
            end
            if (app.currentRegion.regionID(1) > app.numOfRegions)
                app.progressFileName = strcat(app.progressFileName, '_complete.mat');
            else
                app.progressFileName = strcat(app.progressFileName, '_incomplete.mat');
            end
            %             app.progressFileName = strcat(app.progressFileName, '.mat');
            %             [fileName,filePath] = uiputfile('*.mat','Workspace Variables File', app.progressFileName);
            %             if ischar(fileName)
            %uisave(fullfile(filePath,fileName),'-struct','app.targetImage.oneDImage');
            operatorAnalysisResults = app.operatorAnalysisResults;
            operatorName = app.operatorName;
            numOfRegions = app.numOfRegions;
            progressFileName = app.progressFileName;
            currentRegionRowNumber = app.currentRegionRowNumber;
            currentRegion = app.currentRegion;
            targetImage = app.targetImage;
            imResizeRatio = app.imResizeRatio;
            [fileName,filePath] = uiputfile('*.mat','Workspace Variables File', app.progressFileName);
            save(fullfile(filePath,app.progressFileName),'operatorAnalysisResults','operatorName','numOfRegions','progressFileName','currentRegionRowNumber','currentRegion','targetImage','imResizeRatio');
            %             end
            fileName = app.progressFileName;
        end
        
        function [mask,rgbMask] = bloodsmearbinarization(app, rgbImage,colorSpace, binarizationMethod, binarizationSensitivity, openingValue, closingValue, layerNum)
            %used in preanalysimage() function.
            % outputs binary image of the WBCs in blood smear by defined method ( otsu,
            % graythresh or adaptive).
            % sensitivity for adaptive thresholding method is set to 0.9.
            % thershold level is retrived from rgb or gray scale image and performed on
            % s channel of hsv color space as this channel performes better on
            % segmentation of white blood cells.
            applog(app,"H1: ""bloodsmearbinarization"" function called.");
            %             disp('H1: "bloodsmearbinarization" function called.')
            switch colorSpace
                case 'hsv'
                    hsvImage = rgb2hsv(rgbImage);
                    grayscaleImage = rgb2gray(rgbImage);
                    switch layerNum
                        case 'layer 1'
                            selected_channel = hsvImage(:,:,1);% h channel
                        case 'layer 2'
                            selected_channel = hsvImage(:,:,2);% s channel
                        case 'layer 3'
                            selected_channel = hsvImage(:,:,3);% v channel
                        case 'layer 4'
                            selected_channel = hsvImage(:,:,2);% s channel
                    end
%                     selected_channel = hsvImage(:,:,2);
                    selected_channel = imadjust(selected_channel);
                    switch binarizationMethod
                        case 'otsu'
                            [counts, x] = imhist(grayscaleImage, 32);
                            [level, em] = otsuthresh(counts);
                            binaryImage = im2bw(selected_channel, level);
                            binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                        case 'graythresh'
                            level = graythresh(rgbImage);
                            binaryImage = im2bw(selected_channel, level);
                            binaryImage = imerode(binaryImage, strel('disk', openingValue));
                            mask = imdilate(binaryImage, strel('disk', closingValue));
                            mask = imfill(mask);
                        case 'adaptive'
                            level = adaptthresh(selected_channel, binarizationSensitivity);
                            binaryImage = imbinarize(selected_channel, level);
                            binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                            mask = imfill(mask,'holes');
                        case 'manual'
                            % level = adaptthresh(selected_channel, binarizationSensitivity);
                            binaryImage = imbinarize(selected_channel, binarizationSensitivity);
                            %binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                            %mask = bwareaopen(mask,200,4);
                            mask = imfill(mask,'holes');
                            mask = imerode(mask, strel('disk', openingValue));
                            %  mask = imdilate(mask, strel('disk', closingValue));
                            mask = bwareaopen(mask,100,4);%%%% modification needed: area size should be related to image size
                            mask = imfill(mask,'holes');
                            %mask = imopen(binaryImage, strel('disk', closingValue));
                            
                    end
                    
                case 'cmyk'
                    cmykImage = app.rgb2cmyk(rgbImage);
                    y_channel = cmykImage(:,:,3);
                    %y_channel = y_channel .*2;
                    %y_channel = imadjust(y_channel,stretchlim(y_channel),[0,1]);
                    %y_channel = y_channel.*3;
                    %y_channelFilled = imfill(y_channel,26);
                    %y_channel = imdilate(imerode(y_channelFilled,strel('disk',1)),strel('disk',1));
                    %imshow(y_channel)
                    k_channel = cmykImage(:,:,4);
                    %k_channel = k_channel.*2;
                    %k_channel = imadjust(k_channel,stretchlim(k_channel),[0,1]);
                    k_channelFilled = imfill(k_channel);
                    k_channelADJ = imadjust(k_channelFilled,stretchlim(k_channelFilled),[0,1]);
                    %imshow(y_channel)
                    switch binarizationMethod
                        case 'adaptive'
                            
                            klevel = adaptthresh(k_channelADJ, binarizationSensitivity);
                            kMask = imbinarize(k_channelADJ, klevel);
                            
                            %kMaskDilated = imdilate(kMask,strel('disk',2));
                            %mask = uint8(kMaskDilated);
                            mask = uint8(kMask);
                            yROI = y_channel .* mask;
                            yROI = imfill(yROI);
                            yROI = imadjust(imdilate(imerode(yROI,strel('disk',2)),strel('disk',3)));
                            %imshow(yROI)
                            level = adaptthresh(yROI, binarizationSensitivity);
                            binary = imbinarize(yROI, level);
                            binary = imdilate(binary, strel('disk',5));
                            binary = imfill(binary,'holes');
                            binary = imerode(binary,strel('disk',5));
                            %binary = imdilate(binary, strel('disk',2));
                            k_channelMasked = k_channel;
                            k_channelMasked(imcomplement(binary)) = 0;
                            leveltest = adaptthresh(k_channelMasked,0.8);
                            bintest = imbinarize(k_channelMasked,leveltest);
                            bintest = imfill(bintest,'holes');
                            bintest = bwareaopen(bintest,1000,8);
                            k_channelMasked(imcomplement(bintest)) = 0;
                            %imshow(k_channelMasked)
                            
                    end
                    
            end
            %mask = imclose(binaryImage, strel('disk', closingValue));
            rgbMask = rgbImage;
            rgbMask(repmat(~mask,[1 1 3])) = 0;
            clear hsvImage level selected_channel grayscaleImage
        end
        
        function watershedAnalysisResults = singleregionsegmentor(app,rgbImage, oneDimage, cellMask, mainRegionID, houghSensitivity, houghCircleRange, rbcAreaThreshold)
            %singleregionsegmentor Summary of this function goes here
            %   I have developed the function by K channel of CMYK color space as
            %   "oneDimage". using other color spaces and channels should be examined.
            %   offsetType is Offset direction : offset0, offset45, offset90, offset135
            %   offsetNumber is number of points.
            %
            % ID of each final region is an array composed of [main region ID, subregion ID]. subregion ID can be rbc or fragment.
            % WatershedAnalysisResults =
            %   int             int         int                 array       struct
            % {'numObjects',0,'numRBCs',0,'numFragments',0,'fragmentIDs',[],'region',{'id',0,'mask',cellMask,'cell',regionSingle,'GLCMinput',false}}
            disptext = ['H1: "singleregionsegmentor" function called for region no: ', num2str(mainRegionID)];
            applog(app,string(disptext));
            %             disp(disptext)
            %% Defaults:
            if ~exist('houghSensitivity','var')
                houghSensitivity = 0.95; %default value for houghSensitivity
            end
            if ~exist('houghCircleRange','var')
                houghCircleRange = [20,40]; %default value for houghCircleRange
            end
            
            if ~exist('rbcAreaThreshold','var')
                rbcAreaThreshold = 2000; %default value for rbcAreaThreshold
            end
            numRBCs = 0;%????????
            %end Defaluts
            %             imshow(oneDimage,[],'parent', app.CellUIAxes);
            %             sdfgh
            %% preparation
            %             switch offsetType
            %                 case 'offset0'
            %                     glcmOffset = [zeros(offsetNumber,1) (1:offsetNumber)'];
            %                 case 'offset45'
            %                     glcmOffset = [(-1:-1:offsetNumber)' (1:offsetNumber)'];
            %                 case 'offset90'
            %                     glcmOffset = [(-1:-1:offsetNumber)' zeros(offsetNumber,1)];
            %                 case 'offset135'
            %                     glcmOffset = [(-1:-1:offsetNumber)' (-1:-1:offsetNumber)'];
            %                 otherwise
            %                     glcmOffset = [(-1:-1:offsetNumber)' (1:offsetNumber)']; % if the offset type is not defined correctly, offset45 is considered
            %             end
            % in below line I edit the line to test the result. original: regionSingle = double(oneDimage);
            regionSingle = im2double(oneDimage);  %test if double does not deteriorates image data(?!)
            %            imshow(regionSingle,[],'parent', app.CellUIAxes);% just for visualization
            %            asdf
            regionSingle(~cellMask) = NaN; % masking whatever the oneDimage is (like CMYK channel K), by single region mask
            % cellMask is function input and is a parent function result that is the same size as rgbImage.
            %%%test
%             regionSingle(~cellMask) = 0;
            %%%end
            %            imshow(regionSingle,[],'parent', app.CellUIAxes);% just for visualization
            %            asdf
            %app.saveworkspace();
            
            regionStats = regionprops(cellMask, 'Area','BoundingBox', 'Centroid', 'Image', 'MajorAxisLength','MinorAxisLength'); %region properties of selected region that is detected in watershed analysis
            %save('log.mat','regionStats','-mat');
            % cropping single region
            cropboxSize = regionStats.BoundingBox;
            cropBox = size(cropboxSize);
            cropBox(1) = regionStats.BoundingBox(1) -10;
            cropBox(2) = regionStats.BoundingBox(2) -10;
            cropBox(3) = regionStats.BoundingBox(3) +20;
            cropBox(4) = regionStats.BoundingBox(4) +20;
            %app.operatorAnalysisResults.region.position = cropBox;
            regionSinglezoomed = imcrop(regionSingle, cropBox); % This is oneDimage of the zoomed target region.
            cellMaskzoomed = imcrop(cellMask, cropBox); % cropping single region mask. cellMaskzoomed is zoomed mask of single region.
            
            %building rgb single region zoomed
            rChannelregionSingleZoomedRGB = rgbImage(:,:,1);%its not zoomed yet
            rChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            rChannelregionSingleZoomedRGB = imcrop(rChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed
            
            gChannelregionSingleZoomedRGB = rgbImage(:,:,2); %its not zoomed yet
            gChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            gChannelregionSingleZoomedRGB = imcrop(gChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed
            
            bChannelregionSingleZoomedRGB = rgbImage(:,:,3); %its not zoomed yet
            bChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            bChannelregionSingleZoomedRGB = imcrop(bChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed
            %             imshow(bChannelregionSingleZoomedRGB, 'parent', app.CellUIAxes)% just for visualization
            %             asdf
            regionSingleZoomedRGB = cat(3,rChannelregionSingleZoomedRGB, gChannelregionSingleZoomedRGB, bChannelregionSingleZoomedRGB);%zoomed rgb of one cell
            %             regionSingleZoomedRGB(:,:,1) = rChannelregionSingleZoomedRGB;%now it's zoomed
            %             regionSingleZoomedRGB(:,:,2) = gChannelregionSingleZoomedRGB;%now it's zoomed
            %             regionSingleZoomedRGB(:,:,3) = bChannelregionSingleZoomedRGB;%now it's zoomed
            %end of building rgb single region zoomed
            %end of cropping single region
            %end of preparation
            %             imshow(regionSingleZoomedRGB, 'parent', app.CellUIAxes)% just for visualization
            %             asdf
            
            %% Hough Transform
            %calculating the hough circles areas in single region (single cell cluster)
            
            [centers, radii] = imfindcircles(regionSingle, houghCircleRange,'Sensitivity',houghSensitivity); % finding circles in single region houghSensitivity = 0.95
            %%%h = viscircles(centers, radii,'EdgeColor','b'); %Just to visualize the result in development phase
            [houghCentersRowN,houghCentersColN] = size(centers);   %count circle numbers by counting the centers
            if houghCentersRowN ~= 0
                houghArea = zeros(size(radii));
                for circlei =  1:houghCentersRowN
                    houghArea(circlei) = pi*((radii(circlei))^2);%calculate the area by pi*r^2 formula
                end
            else
                houghArea = 0;
            end
            disptext = ['H1>singleregionsegmentor: Hough Transform Analysis for region no: ', num2str(mainRegionID),' detected ',num2str(houghCentersRowN),' centers'];
            applog(app,string(disptext));
            %             disp(disptext)
            
            %% Watershed Analysis
            % This can be a seperate function.
            %define empty containers
            disptext = ['H1>singleregionsegmentor: Watershed Analysis for region no: ', num2str(mainRegionID),' started'];
            applog(app,string(disptext));
            %             disp(disptext)
            watershedAnalysisResults = struct(  'numObjects',0,...      any subregion
                'numRBCs',0,...         any subregion that is larger than 'rbcAreaThreshold'
                'numFragments',0,...    any sub-region (object) that is smaller than 'rbcAreaThreshold'
                'fragmentIDs',[],...    ID of fregment including the main region ID  'fragmentIDs',[],...    ID of fregment including the main region ID
                'region',struct('regionID',uint16([mainRegionID, 00]),...
                'position',cropBox,...
                'mask',cellMaskzoomed,... main single region mask is used as defualt value
                'cell',regionSinglezoomed,... main single region masked image is used as default value
                'cellRGB',regionSingleZoomedRGB,...
                'isRBC',false,...
                'GLCM', [],...
                'operatorAnalysis', 1,...
                'operatorCellQt',1));
            % in this struct, "fragment" means any sub-region (object) that is smaller than 'rbcAreaThreshold'
            %ID = 0 means that watershed algorithm did not recogonized any regions.
            
            fragmentsTotalArea = 0;
            wsrChannelregionSingleZoomedRGB = rChannelregionSingleZoomedRGB;
            wsgChannelregionSingleZoomedRGB = gChannelregionSingleZoomedRGB;
            wsbChannelregionSingleZoomedRGB = bChannelregionSingleZoomedRGB;
            % end define empty containers
            %
            wsbinarry = cellclusterwatershed(cellMaskzoomed); % applying Watershed algorithm on binarry image of single region
            
            %testcode
            %             imshow(wsbinarry, 'parent', app.CellUIAxes);
            %END
            %             size(wsbinarry)
            cc = bwconncomp(wsbinarry);
            NumWatershedRegions = cc.NumObjects;
            WatershedRegionsStats = regionprops(cc, 'Area');
            disptext = ['H1>singleregionsegmentor: Number of Watershed Analysis detected regions is ', num2str(NumWatershedRegions)];
            applog(app,string(disptext));
            %             disp(disptext)
            %assessing watershed regions
            if isequal(NumWatershedRegions, 0)|| NumWatershedRegions==1
                watershedAnalysisResults.numObjects = 1;
                watershedAnalysisResults.numRBCs = 1; %Watershed algorithm did not recogonized any regions so there should be at least one RBC, because it was a region from final mask.
                watershedAnalysisResults.numFragments = 0;
                watershedAnalysisResults.region.regionID = uint16([mainRegionID, 0]);%ID = 0 means that watershed algorithm did not recogonized any regions.
                watershedAnalysisResults.region.mask = cellMaskzoomed;
                watershedAnalysisResults.region.cell = regionSinglezoomed;
                watershedAnalysisResults.region.isRBC = true;
                watershedAnalysisResults.region.position = cropBox*(1/app.imResizeRatio);
            else
                for wscelli = 1:NumWatershedRegions   %investigate each watershed region
                    wscellMask = false(size(cellMaskzoomed)); % building a mask the same size as single region image
                    %                     size(wscellMask)
                    %                     imshow(wscellMask, 'parent', app.CellUIAxes);
                    %                     as
                    wscellMask(cc.PixelIdxList{wscelli}) = true;% building watershed single cell mask
                    
                    wscellMask = imdilate(wscellMask,strel('disk',3)); % dilating the mask to preserve cell image data
                    wsregionSingleZoomed = regionSinglezoomed;% defining base single cell image
                    wsregionSingleZoomed(~wscellMask) = NaN; % extracting watershed single cell.
                    %                     imshow(wsregionSingleZoomed,[],'InitialMagnification','fit')
                    
                    %masking watershed single cell result in rgb image
                    wsrChannelregionSingleZoomedRGB = rChannelregionSingleZoomedRGB;
                    wsgChannelregionSingleZoomedRGB = gChannelregionSingleZoomedRGB;
                    wsbChannelregionSingleZoomedRGB = bChannelregionSingleZoomedRGB;
                    wsrChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsgChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsbChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsRegionSingleZoomedRGB = regionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,1) = wsrChannelregionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,2) = wsgChannelregionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,3) = wsbChannelregionSingleZoomedRGB;
                    
                    %end of masking rgb image
                    %start test
                    imshow(wsRegionSingleZoomedRGB, 'parent', app.CellUIAxes);
                    %                     w = waitforbuttonpress;
                    %end test
                    %start test
                    %watershedAnalysisResults.region(wscelli).regionID(2) = wscelli;
                    watershedAnalysisResults.region(wscelli).regionID = uint16([mainRegionID, wscelli]);
                    %end test
                    watershedAnalysisResults.region(wscelli).mask = wscellMask;
                    watershedAnalysisResults.region(wscelli).cell = wsregionSingleZoomed;
                    watershedAnalysisResults.region(wscelli).cellRGB = wsRegionSingleZoomedRGB;
                    watershedAnalysisResults.region(wscelli).isRBC = true;
                    watershedAnalysisResults.region(wscelli).operatorAnalysis = 1;
                    watershedAnalysisResults.region(wscelli).operatorCellQt = 1;
                    watershedAnalysisResults.region(wscelli).position = cropBox*(1/app.imResizeRatio);
                    %                     wscelli
                    %                     WatershedRegionsStats.Area
                    %                     WatershedRegionsStats.Area(wscelli)
                    if WatershedRegionsStats(wscelli).Area < rbcAreaThreshold
                        watershedAnalysisResults.numFragments = watershedAnalysisResults.numFragments + 1;
                        watershedAnalysisResults.fragmentIDs = [watershedAnalysisResults.fragmentIDs,wscelli]; % fragment IDs are stored to be used in sum of fragment areas
                        watershedAnalysisResults.region(wscelli).isRBC = false; %fragments are not suitable for GLCM calculation
                    end
                end
                %calculating quantity of each type of object.
                watershedAnalysisResults.numObjects = NumWatershedRegions;
                watershedAnalysisResults.numRBCs = watershedAnalysisResults.numObjects - watershedAnalysisResults.numFragments;
                
                
                if watershedAnalysisResults.numFragments ~=0
                    for fragmenti = 1:length(watershedAnalysisResults.fragmentIDs)
                        %                         watershedAnalysisResults.numFragments
                        %                         fragmenti
                        %                         watershedAnalysisResults
                        %                         watershedAnalysisResults.fragmentIDs
                        %                         watershedAnalysisResults.fragmentIDs(fragmenti)
                        %                         size(watershedAnalysisResults.fragmentIDs)
                        %                         WatershedRegionsStats(watershedAnalysisResults.fragmentIDs(fragmenti))
                        %                         watershedAnalysisResults.fragmentIDs
                        %                         watershedAnalysisResults.fragmentIDs(fragmenti)
                        fragmentsTotalArea = fragmentsTotalArea + WatershedRegionsStats(watershedAnalysisResults.fragmentIDs(fragmenti)).Area; %calculate total fragments area.
                    end
                end
                
                % In line below integer division of total fragments areas are added
                % to rbc count. maybe it is better to add just number one.
                % !!!!!CHECK!!!!!
                watershedAnalysisResults.numRBCs = watershedAnalysisResults.numRBCs + idivide(int32(fragmentsTotalArea),int32(rbcAreaThreshold));
                disptext = ['H1>singleregionsegmentor: Watershed Analysis results-> RBC:',num2str(watershedAnalysisResults.numRBCs)];
                applog(app,string(disptext));
                %                 disp(disptext)
                %END OF calculating quantity of each type of object.
            end
            %calculating the GLCM
            %             for regioni = 1 : watershedAnalysisResults.numObjects
            %                 if watershedAnalysisResults.region(regioni).isRBC
            %                     glcms = graycomatrix(watershedAnalysisResults.Region(regioni).cell, 'offset', glcmOffset);
            %                     glcmStats = graycoprops(glcms,'all');
            %                     watershedAnalysisResults.region(regioni).GLCM = glcmStats;
            %                 end
            %             end
            %             [regionHoughAreaN, coln] = size(houghArea);
            %             %regionforGLCM = size(houghArea); % Matrix to save GLCM data of each single region in it. ?!!
            %             subAnalysisResults = struct('mainRegionID',mainRegionID,...
            %                 'numObjects',0,...
            %                 'numRBCs',0,...
            %                 'numFragments',0,...
            %                 'region',struct('rbcID',uint16([mainRegionID, 00]),...
            %                 'mask',cellMaskzoomed,...
            %                 'cell',regionSinglezoomed,...
            %                 'GLCM',[])); %this struct does not include fragment data
        end
        
        function c = rgb2cmyk(app,c)
            % RGB2CMYK   Converts between RGB- and CMYK- Colors
            %
            % RedGreenBlue <---> CyanMangentaYellowBlack
            %
            % CMYK = RGB2CMYK(  RGB )
            %
            %  RGB = RGB2CMYK( CMYK )
            %
            % RGB :  N by 3   , N Colors
            % CMYK:  N by 4
            %
            %
            applog(app,"H1: ""rgb2cmyk"" function called.");
            %             disp('H1: "rgb2cmyk" function called.')
            s = size(c);
            n = size(s,2);
            
            m = s(2+(n==3));
            
            if ~( isnumeric(c) & any( n == [ 2  3 ]) & ...
                    any( m == [ 3  4 ] ) )
                error('Input must be a RGB or CMYK ColorMatrice.');
            end
            
            if isempty(c)
                return
            end
            
            u8 = strcmp(class(c),'uint8');
            if u8
                c = double(c)/255;
            end
            
            sub = { ':'  ':' };
            sub = sub(1:(1+(n==3)));
            
            if m == 3
                %  RGB --> CMYK
                
                c = 1 - c;
                k = min(c,[],n);
                
                c = c - k(sub{:},[1 1 1]);
                
                c = cat( n , c , k );
                
            else
                % CMYK --> RGB
                
                c = 1 - ( c(sub{:},[1 2 3]) + c(sub{:},[4 4 4]) );
                
            end
            
            
            if u8
                c = uint8(round(c*255));
            end
        end
        
        function preanalysimage(app, imageResizeRatio, RBCminSize)
            % used when "segmentation' switch is pressed
            applog(app,"H1: ""preanalysimage"" function called.");
            %             disp('H1: "preanalysimage" function called.')
            if ~exist('imageResizeRatio','var')
                imageResizeRatio = app.imResizeRatio; %default value for imageResizeRatio is 0.5
            end
            
            %I have used to stages of binarization to remove WBCs.
            %app.targetImage.rgbImage = imread(filePath);%%%%%% modification needed: check image size and decide on size reduction and also bwareaopen input parameter.
            app.targetImage.rgbImage = imresize(app.targetImage.rgbImage,imageResizeRatio); %reducing image size in half
            [mask, rgbmask] = app.bloodsmearbinarization(app.targetImage.rgbImage,...%finding WBCs
                app.optionsvalues.wbccolorSpace,...%hsv
                app.optionsvalues.wbcbinarizationMethod,...%manual
                app.optionsvalues.wbcbinarizationSensitivity,...%0.98
                app.optionsvalues.wbcBWopeningValue,...%3
                app.optionsvalues.wbcBWclosingValue,...%2
                app.optionsvalues.wbccolorLayer); %layer 2
            mask = imdilate(mask,strel('disk',5));
            
            %%%%hsv = rgb2hsv(imrgb);
            %%%%sChannel = hsv(:,:,2);
            %%%%imadjusted = imadjust(sChannel);
            [mask2,rgbmask2] = app.bloodsmearbinarization(app.targetImage.rgbImage,...%finding all objects including RBC and WBCs
                app.optionsvalues.rbccolorSpace,...%hsv
                app.optionsvalues.rbcbinarizationMethod,...%adaptive
                app.optionsvalues.rbcbinarizationSensitivity,...%0.9
                app.optionsvalues.rbcBWopeningValue,...%5
                app.optionsvalues.rbcBWclosingValue,...%1
                app.optionsvalues.rbccolorLayer);%layer 2
            
            
            %removing WBCs from the mask
            maskfinal = mask2 - mask;
            maskfinal = bwareaopen(maskfinal,RBCminSize,4); %The operator should be able to change the value 2000 or it should be define related to image size or microscope factor.
            %                         imshow(maskfinal,[],'parent', app.CellUIAxes);% just for visualization
            %             asd
            
            app.targetImage.mask = imclearborder(maskfinal,4);
            %             imshow(app.targetImage.mask,'parent',app.CellUIAxes);
            
            %END removing WBCs from the mask
            
            app.targetImage.connectedComponents = bwconncomp(app.targetImage.mask);
            %             results = cc;
            % from here I use k channel of cmyk color space for RBC extraction.
            % Using H channel of HSV or G channel of RGB or even total grayscale image
            % should be investigated
            % imshow(maskfinal)
            
            cmyk = app.rgb2cmyk(app.targetImage.rgbImage);
            
            %Define type of 1D gray scale image to be masked for RBC counting (singe cell
            %segemntation)(This 1D image would be masked and ROIs in it would be analysed.?)
            
            app.targetImage.oneDImage = cmyk(:,:,4); %k channel is choosed to be masked
            
            app.targetImage.oneDImage = imadjust(app.targetImage.oneDImage);
            %             imshow(app.targetImage.oneDImage,'parent',app.CellUIAxes);
            %             dfgh
            
            
            % end of 1D gray scale image definition
            %numOfCellRegions = app.targetImage.connectedComponents.NumObjects;
            applog(app,"H1: ""preanalysimage"" function execution finished.");
            %             disp('H1: "preanalysimage" function execution finished.')
        end
        
        function operatorcurrentcellupdate(app, lastRegionRowNumber, cellTypeSelection, cellQtSelection,pressedButton)
            %This function ????
            
            currentRegionRectangle = rectangle(app.SmearUIAxes,'EdgeColor','b','LineWidth',1,'Position',app.operatorAnalysisResults.region(lastRegionRowNumber).position);
            
            %app.operatorAnalysisResults.region have one more rows and row 1 is empty.
            %starting row is always 2 and is controlled outside this function and this is object number 1
            totalRegionsNumber = app.operatorAnalysisResults.softwareNumObjects;
            if strcmp(pressedButton,'next')
                %                 disp('next pressed')
                if (lastRegionRowNumber == (totalRegionsNumber+1))
                    currentRowNumber = lastRegionRowNumber;
                else
                    currentRowNumber = lastRegionRowNumber + 1;
                end
                app.progressIsSaved = false;
            elseif strcmp(pressedButton,'prev')
                %                 disp('prev pressed')
                currentRowNumber = lastRegionRowNumber - 1;
            end
            %START -- Update Operator analysis reults structure with UI right side state when pressing next/prev buttons
            if (strcmp(pressedButton,'next')&&(lastRegionRowNumber>1))%~isequal(lastRegionRowNumber,2)   %This if statement retrieves operator segmentation area.
                %                 disp('region saved')
                app.operatorAnalysisResults.region(lastRegionRowNumber).operatorAnalysis = cellTypeSelection;
                app.operatorAnalysisResults.region(lastRegionRowNumber).operatorCellQt = cellQtSelection;
                
                %                 if (app.operatorAnalysisResults.lastAnalysedRegionRow < lastRegionRowNumber)%cells that operator has not analysed before.
                %                     app.operatorAnalysisResults.operatorNumObjects = app.operatorAnalysisResults.operatorNumObjects + cellQtSelection;
                %                     switch cellTypeSelection
                %                         case app.operatorRegionType.unknown%3%unknown
                %                             app.operatorAnalysisResults.operatorNumFragments = app.operatorAnalysisResults.operatorNumFragments + cellQtSelection;
                %                             app.UnknownRegionValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumFragments);
                %                         case app.operatorRegionType.normal%1%normal
                %                             app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs + cellQtSelection;
                %                             app.operatorAnalysisResults.operatorNumNormalRBCs = app.operatorAnalysisResults.operatorNumNormalRBCs + cellQtSelection;
                %                             app.NormalRBCValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumNormalRBCs);
                %                         otherwise %abnormal
                %                             app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs + cellQtSelection;
                %                             app.operatorAnalysisResults.operatorNumAbnormalRBCs = app.operatorAnalysisResults.operatorNumAbnormalRBCs + cellQtSelection;
                %                             app.AbnormalRBCValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumAbnormalRBCs);
                %                     end
                %                     app.operatorAnalysisResults.lastAnalysedRegionRow = lastRegionRowNumber;
                %                 else%operator is reviweing and editing analysed cells.
                %                     %Operator may do one/some/all of these:
                %                     %1)change cell type
                %                     %2)change cell Qt
                %                     %3)change abnormal cell type
                %                     %This else statement should consider all these senarios
                %                     %operator may change several items, several times before pressing "next" or "Prev". So the
                %                     %final selection should be compaired with database.
                %                     %In case of any true flag, current selection should be compaired with database to take the correct action.
                %
                %
                %                 end
            end
            
            
            %START ---- update UI right side state (button states and cell Qt ...) with analysis results previously saved
            if ((lastRegionRowNumber <= (totalRegionsNumber+1))&&(lastRegionRowNumber > 1)) %This statement fills operator sagmentation UI area.
                app.currentRegionRowNumber = currentRowNumber;
                currentregionID = app.operatorAnalysisResults.region(currentRowNumber).regionID;
                %%%%test
%                 position = app.operatorAnalysisResults.region(currentRowNumber).position
%                 currentRowNumber = currentRowNumber
                currentRegionRectangle = rectangle(app.SmearUIAxes,'EdgeColor','g','LineWidth',1,'Position',app.operatorAnalysisResults.region(currentRowNumber).position);
                %%%%end test
                imshow(app.operatorAnalysisResults.region(currentRowNumber).cellRGB,'parent',app.CellUIAxes);%Showing cell image
                app.RegionIDLabel.Text = strcat(num2str(currentregionID(1)),'_',num2str(currentregionID(2)));%showing region and cell id
                %app.operatorAnalysisResults.region(currentRowNumber).operatorAnalysis
                switch app.operatorAnalysisResults.region(currentRowNumber).operatorAnalysis
                    case app.operatorRegionType.normal
                        app.NormalRBCButton.Value = 1;
                        app.Abnormalityknob.Enable = 'off';
                    case app.operatorRegionType.abnormal.target
                        app.AbnormalRBCButton.Value = 1;
                        app.Abnormalityknob.Value = num2str(21);
                        app.Abnormalityknob.Enable = 'on';
                    case app.operatorRegionType.abnormal.spherocyte
                        app.AbnormalRBCButton.Value = 1;
                        app.Abnormalityknob.Value = num2str(22);
                        app.Abnormalityknob.Enable = 'on';
                    case app.operatorRegionType.abnormal.hyper
                        app.AbnormalRBCButton.Value = 1;
                        app.Abnormalityknob.Value = num2str(23);
                        app.Abnormalityknob.Enable = 'on';
                    case app.operatorRegionType.abnormal.hypo
                        app.AbnormalRBCButton.Value = 1;
                        app.Abnormalityknob.Value = num2str(24);
                        app.Abnormalityknob.Enable = 'on';
                    case app.operatorRegionType.unknown
                        app.UnknownButton.Value = 1;
                        app.Abnormalityknob.Enable = 'off';
                end
                app.CellQtDropDown.Value = num2str(app.operatorAnalysisResults.region(currentRowNumber).operatorCellQt);%app.operatorAnalysisResults.region(currentRowNumber).operatorCellQt;
            end
            
            % if statements to control "next" and "prevous" buttons
            if (currentRowNumber <= (totalRegionsNumber+1))
                app.NextRegion.Enable = 'on';
            else
                app.NextRegion.Enable = 'off';
            end
            if (currentRowNumber > 2)
                %fprintf('currentRowNumber  %d is larger than 2. lastRegionRowNumber = %d\n',currentRowNumber,lastRegionRowNumber)
                %disp('lastRegionRowNumber > 1')
                app.PrevRegion.Enable = 'on';
            else
                app.PrevRegion.Enable = 'off';
            end
            %             if (lastRegionRowNumber == totalRegionsNumber)
            %                 app.NextRegion.Enable = 'off';
            %             elseif (lastRegionRowNumber == 1)
            %                 app.PrevRegion.Enable = 'off';
            %             end
            %END -- update UI right side state
            
        end
        
        function operatorsegmentationvaluesupdate(app, totalRegionsNumber, state)
            %This function counts and fills operator analysis result fields in start and fills UI operator fields when in progress
            %state= 'start' or 'inprocess'
            %totalRegionsNumber is used as region row number
            operatorSegmentationValues = struct('normalRBC', 0,...% is the number of each cell type defined by operator
                'abnormalRBC',0,...
                'unknown',0);
            switch state
                case 'start'%The main reason for this part is that the software should be able to continue processing an incomplete saved process.
                    
                    %This for loop counts each cell type from the software analysis result
                    for regionCounter= 2:totalRegionsNumber
                        cellType = app.operatorAnalysisResults.region(regionCounter).operatorAnalysis;
                        if cellType == app.operatorRegionType.normal
                            operatorSegmentationValues.normalRBC = operatorSegmentationValues.normalRBC + 1;
                        elseif isequal(cellType , app.operatorRegionType.abnormal.target)||isequal(cellType , app.operatorRegionType.abnormal.spherocyte)||isequal(cellType,app.operatorRegionType.abnormal.hyper)||isequal(cellType,app.operatorRegionType.abnormal.hypo)
                            operatorSegmentationValues.abnormalRBC = operatorSegmentationValues.abnormalRBC + 1;
                        elseif cellType == app.operatorRegionType.unknown
                            operatorSegmentationValues.unknown = operatorSegmentationValues.unknown + 1;
                        end
                    end
                    %                     app.operatorAnalysisResults.operatorNumNormalRBCs = operatorSegmentationValues.normalRBC;
                    %                     app.operatorAnalysisResults.operatorNumAbnormalRBCs = operatorSegmentationValues.abnormalRBC;
                    %                     app.operatorAnalysisResults.operatorNumFragments = operatorSegmentationValues.unknown;
                    %                     app.operatorAnalysisResults.operatorNumObjects = operatorSegmentationValues.normalRBC + operatorSegmentationValues.abnormalRBC+ operatorSegmentationValues.unknown;
                    %                     app.operatorAnalysisResults.operatorNumRBCs = operatorSegmentationValues.normalRBC + operatorSegmentationValues.abnormalRBC;
                    
                case 'inprocess'
                    %add codes to update operatorNums according operator selections, after selecting next or previous by him/her
                    app.AbnormalRBCValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumAbnormalRBCs);
                    app.NormalRBCValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumNormalRBCs);
                    app.UnknownRegionValueLabel.Text = num2str(app.operatorAnalysisResults.operatorNumFragments);
            end
        end
        
        function results = operatoranalysisreader(app)
            %This function should be executed in "next" and "previous" buttonPushed callback
            %to get the operator analysis results and inject it to master analysis struct (operatorAnalysisResults).
            %It gets private variable "operatorButtonSelection".
            
        end
        
        function value = buttongroupvalueextract(app, text)
            switch text
                case 'Normal RBC'
                    value = app.operatorRegionType.normal;
                case 'Abnormal RBC'
                    value = 2;%Abnormal
                case 'Unknown'
                    value = app.operatorRegionType.unknown;
                case  'Target'
                    value = app.operatorRegionType.abnormal.target;
                case 'Spherocyte'
                    value = app.operatorRegionType.abnormal.spherocyte;
                case 'Hyper'
                    value = app.operatorRegionType.abnormal.hyper;
                case 'Hypo'
                    value = app.operatorRegionType.abnormal.hypo;
                case '1'
                    value = 1;
                case '2'
                    value = 2;
                case '3'
                    value = 3;
                case '4'
                    value = 4;
                case '5'
                    value = 5;
                case '6'
                    value = 6;
                case '7'
                    value = 7;
                otherwise
                    value = 0;
            end
        end
        
        function applog(app, logtext)
            fid = fopen('H1Logfile.txt', 'a');
            if fid == -1
                error('Cannot open log file.');
            end
            fprintf(fid, '%s: %s\n', datestr(now, 0), logtext);
            fclose(fid);
        end
    end
    methods (Access = public)
        
        function getoptionsvalues(app, savedOptions)
            app.optionsvalues = savedOptions;
            app.optionsButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.CellUIAxes.Visible = 'off';
            app.SmearUIAxes.Visible = 'off';
            %app.currentRegion = 00;
            axis(app.CellUIAxes, 'image');
            axis(app.SmearUIAxes, 'image');
            
            app.Lamp.Color = 'yellow';
            app.Lamp.Enable = 'off';
            
            app.CellQtDropDown.Enable = 'off';
            app.Abnormalityknob.Enable = 'off';
            app.NormalRBCButton.Enable = 'off';
            app.AbnormalRBCButton.Enable = 'off';
            app.UnknownButton.Enable = 'off';
            app.StartSegmentationSwitch.Enable = 'off';
            app.SaveProgressButton.Enable = 'off';
            app.LoadProgressButton.Enable = 'on';
            app.PrevRegion.Enable = 'off';
            app.NextRegion.Enable = 'off';
            app.StartSegmentationSwitch.Enable = 'off';
            app.progressIsSaved = true;
            MSGID = 'images:graycomatrix:scaledImageContainsNan';
            warning('off', MSGID);
            
            fid = fopen('H1Logfile.txt', 'w');
            fclose(fid);
            
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            applog(app, "H1: UI ""Load Image"" botton pressed");
            disp('H1: UI "Load Image" botton pressed')
            app.Lamp.Color = 'yellow';
            app.Lamp.Enable = 'on';
            filterspec = {'*.jpg;*.png','Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.targetImage.filePath = [p f];
                updatesmearimage(app, app.targetImage.filePath, f);
            else
                app.Lamp.Enable = 'off';
            end
            app.StartSegmentationSwitch.Enable = 'on';
        end

        % Value changed function: StartSegmentationSwitch
        function StartSegmentationSwitchValueChanged(app, event)
            
            
            %app.currentRegion = 0;
            toggleValue = app.StartSegmentationSwitch.Value;
            if isequal(toggleValue,'0') %switch off
                app.Lamp.Color = 'red';
                app.Lamp.Enable = 'on';
                %ask to save data if complete
                if ~(app.progressIsSaved)
                    uialert(app.UIFigure, 'Save your progress before closing the app.', 'Save data','Modal',true);
                    app.StartSegmentationSwitch.Value = '1';
                else
                    app.CellQtDropDown.Enable = 'off';
                    app.Abnormalityknob.Enable = 'off';
                    app.NormalRBCButton.Enable = 'off';
                    app.AbnormalRBCButton.Enable = 'off';
                    app.UnknownButton.Enable = 'off';
                    %app.StartSegmentationSwitch.Enable = 'off';
                    app.SaveProgressButton.Enable = 'on';
                    app.LoadProgressButton.Enable = 'on';
                    app.PrevRegion.Enable = 'off';
                    app.NextRegion.Enable = 'off';
                    app.OperatorNameEditField.Enable = 'on';
                    app.softwaresegmentationSwitch.Enable = 'off';
                    app.LoadImageButton.Enable = 'on';
                    app.Lamp.Enable = 'off';
                end
                
            elseif isequal(toggleValue,'1') %switched ON
                if app.loadProgressDone
                    app.Abnormalityknob.Enable = 'off';
                    app.CellQtDropDown.Enable = 'on';
                    app.NormalRBCButton.Enable = 'on';
                    app.AbnormalRBCButton.Enable = 'on';
                    app.UnknownButton.Enable = 'on';
                    %app.StartSegmentationSwitch.Enable = 'off';
                    app.SaveProgressButton.Enable = 'on';
                    app.LoadProgressButton.Enable = 'off';
                    app.PrevRegion.Enable = 'on';
                    app.NextRegion.Enable = 'on';
                    app.StartSegmentationSwitch.Enable = 'on';


                    %app.DetectedRegionValueLabel.Text = num2str(app.targetImage.connectedComponents.NumObjects);
                    %app.currentRegion.regionID = [00,00];
                    %save('H1.mat');
                    app.Lamp.Color = 'green';
                    app.Lamp.Enable = 'on';
                else
                    if isempty(app.OperatorNameEditField.Value)
                        uialert(app.UIFigure, 'Enter your unique operator name in operator name field.', 'operator name');
                        app.Lamp.Color = 'red';
                        app.Lamp.Enable = 'on';
                        app.StartSegmentationSwitch.Value = '0';
                    else
                        app.LoadProgressButton.Enable = 'off';
                        applog(app,"H1: segmentation process started.");
                        disp('H1: segmentation process started.')
                        app.MessageBox.Value = '-> software segmentation in progress. PLEASE WAIT!';
                        drawnow;
                        app.operatorName = app.OperatorNameEditField.Value;
                        app.OperatorNameEditField.Enable = 'off';
                        app.LoadImageButton.Enable = 'off';
                        app.operatorAnalysisResults.operatorName = app.operatorName;
                        
                        
                        %%%%%
                        app.preanalysimage(app.optionsvalues.imageResizeRatio, app.optionsvalues.RBCminimumSize);
                        %%%%%
                        disptext = ['app.targetImage.connectedComponents.NumObjects : ', num2str(app.targetImage.connectedComponents.NumObjects)];
                        applog(app,string(disptext));
                        disp(disptext)
                        
                        %%%%%
                        app.analysproperties(4,'offset0');
                        %%%%%
%                         app.analysproperties(4,'omni');
                        
                        
                        app.Abnormalityknob.Enable = 'off';
                        app.CellQtDropDown.Enable = 'on';
                        app.NormalRBCButton.Enable = 'on';
                        app.AbnormalRBCButton.Enable = 'on';
                        app.UnknownButton.Enable = 'on';
                        %app.StartSegmentationSwitch.Enable = 'off';
                        app.SaveProgressButton.Enable = 'on';
                        app.LoadProgressButton.Enable = 'off';
                        app.PrevRegion.Enable = 'on';
                        app.NextRegion.Enable = 'on';
                        app.StartSegmentationSwitch.Enable = 'on';


                        app.DetectedRegionValueLabel.Text = num2str(app.targetImage.connectedComponents.NumObjects);
                        app.currentRegion.regionID = [00,00];
                        regionRowNumber = 2;
                        %save('H1.mat');
                        app.Lamp.Color = 'green';
                        app.Lamp.Enable = 'on';
                        applog(app,"H1: software segmentation process finished.");
                        disp('H1: software segmentation process finished.')
                        %test = app.currentRegion;
                        %save('log.mat','-struct','test','-mat');
                        %                     while app.currentRegion.regionID(1) <= app.targetImage.connectedComponents.NumObjects
                        
                        %                     end
                        %start segmentation and update gauge in the mean while.
                        if isequal(app.operatorAnalysisResults.softwareNumObjects, 0)
                            uialert(app.UIFigure, 'No RBC Found!', 'error', 'Icon','error', 'Modal',true);
                        else
                            currentregionID = app.operatorAnalysisResults.region(regionRowNumber).regionID;
                            %regionRowNumber
                            imshow(app.operatorAnalysisResults.region(regionRowNumber).cellRGB,'parent',app.CellUIAxes);
                            app.RegionIDLabel.Text = strcat(num2str(currentregionID(1)),'_',num2str(currentregionID(2)));
                            app.operatorsegmentationvaluesupdate(app.operatorAnalysisResults.softwareNumObjects+1,'start');
                        end
                        
                        app.PrevRegion.Enable = 'off';
                        app.MessageBox.Value = '-> software segmentation done';
                    end
                end
            end
            
        end

        % Button pushed function: SaveProgressButton
        function SaveProgressButtonPushed(app, event)
            applog(app,"H1: ""save progress"" botton pressed.");
            disp('H1: "save progress" botton pressed.')
            saveResult = saveworkspace(app);
            if isequal(saveResult,0)
                app.progressIsSaved = false;
                uialert(app.UIFigure, 'could not save the progress!', 'error', 'Icon','error', 'Modal',true);
                app.SaveProgressButton.Enable = 'on';
            else
                app.progressIsSaved = true;
                uialert(app.UIFigure, 'You saved your progress', 'message', 'Icon','success', 'Modal',true);
                app.SaveProgressButton.Enable = 'off';
                app.LoadProgressButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadProgressButton
        function LoadProgressButtonPushed(app, event)
            app.Lamp.Color = 'yellow';
            app.Lamp.Enable = 'on';
            filterspec = {'*.mat','MAT-Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.loadFilePath = [p f];
                app.loadedFile = load(app.loadFilePath);
                uialert(app.UIFigure, 'File loaded successfully', 'loading result', 'Icon','success', 'Modal',true);
            else
                app.Lamp.Enable = 'off';
                uialert(app.UIFigure, 'Could not load the file! ', 'loading result','Icon','error', 'Modal',true);
            end
            app.operatorAnalysisResults = app.loadedFile.app.operatorAnalysisResults;
            app.operatorName = app.loadedFile.app.operatorName;
            app.numOfRegions = app.loadedFile.app.numOfRegions;
            app.progressFileName = app.loadedFile.app.progressFileName;
            app.currentRegion = app.loadedFile.app.currentRegion;
            app.targetImage = app.loadedFile.app.targetImage;
            app.singleRegionAnalysisResults = app.loadedFile.app.singleRegionAnalysisResults;
            app.DetectedRegionValueLabel.Text = num2str(app.targetImage.connectedComponents.NumObjects);
            %save('test');
            
            imshow(app.targetImage.rgbImage,'parent',app.SmearUIAxes);
            app.OperatorNameEditField.Value = app.operatorName;
            app.operatorsegmentationvaluesupdate(app.operatorAnalysisResults.softwareNumObjects,'start');
            app.loadProgressDone = true;
            app.SaveProgressButton.Enable = 'on';
            app.StartSegmentationSwitch.Enable = 'on';
            app.Lamp.Color = 'green';
            app.Lamp.Enable = 'on';
            
            
        end

        % Button pushed function: NextRegion
        function NextRegionButtonPushed(app, event)
            switch app.CellstateButtonGroup.SelectedObject.Text
                case 'Normal RBC'
                    cellType = 1;
                case 'Unknown'
                    cellType = 3;
                case 'Abnormal RBC'
                    cellType = str2num(app.Abnormalityknob.Value);
            end
            %app.operatorAnalysisResults.lastAnalysedRegionRow = app.currentRegionRowNumber;
            app.operatorcurrentcellupdate(app.currentRegionRowNumber, cellType, str2double(app.CellQtDropDown.Value),'next');
            app.operatorsegmentationvaluesupdate(app.operatorAnalysisResults.softwareNumObjects,'inprocess');

        end

        % Button pushed function: PrevRegion
        function PrevRegionButtonPushed(app, event)
            switch app.CellstateButtonGroup.SelectedObject.Text
                case 'Normal RBC'
                    cellType = 1;
                case 'Unknown'
                    cellType = 3;
                case 'Abnormal RBC'
                    cellType = app.Abnormalityknob.Value;
            end
            app.operatorcurrentcellupdate(app.currentRegionRowNumber, cellType, str2double(app.CellQtDropDown.Value),'prev');
            app.operatorsegmentationvaluesupdate(app.operatorAnalysisResults.softwareNumObjects,'inprocess');
        end

        % Selection changed function: CellstateButtonGroup
        function CellstateButtonGroupSelectionChanged(app, event)
            app.operatorEditAnalysed.cellStateEditFlag = true;
%             app.operatorEditAnalysed.cellStateOld = app.buttongroupvalueextract(event.OldValue.Text);
%             app.operatorEditAnalysed.cellStateNew = app.buttongroupvalueextract(app.CellstateButtonGroup.SelectedObject.Text);
            selectedButton = app.CellstateButtonGroup.SelectedObject;
            %previousSelectedButtonText = event.OldValue.Text;
            if strcmp(selectedButton.Text, 'Abnormal RBC')
                app.Abnormalityknob.Enable = 'on';
            else
                app.Abnormalityknob.Enable = 'off';
            end
%             if strcmp(previousSelectedButtonText,'Normal RBC')
%                 app.operatorAnalysisResults.operatorNumNormalRBCs = app.operatorAnalysisResults.operatorNumNormalRBCs - 1;
%                 switch selectedButton.Text
%                     case 'Unknown'
%                         app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs - 1;
%                         app.operatorAnalysisResults.operatorNumFragments = app.operatorAnalysisResults.operatorNumFragments + 1;
%                     case 'Abnormal RBC'
%                         app.operatorAnalysisResults.operatorNumAbnormalRBCs = app.operatorAnalysisResults.operatorNumAbnormalRBCs + 1;
%                 end
%             elseif strcmp(previousSelectedButtonText,'Abnormal RBC')
%                 app.operatorAnalysisResults.operatorNumAbnormalRBCs = app.operatorAnalysisResults.operatorNumAbnormalRBCs - 1;
%                 switch selectedButton.Text
%                     case 'Unknown'
%                         app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs - 1;
%                         app.operatorAnalysisResults.operatorNumFragments = app.operatorAnalysisResults.operatorNumFragments + 1;
%                     case 'Normal RBC'
%                         app.operatorAnalysisResults.operatorNumNormalRBs = app.operatorAnalysisResults.operatorNumNormalRBCs + 1;
%                 end
%             elseif strcmp(previousSelectedButtonText,'Unknown')
%                 app.operatorAnalysisResults.operatorNumFragments = app.operatorAnalysisResults.operatorNumFragments - 1;
%                 switch selectedButton.Text
%                     case 'Abnormal RBC'
%                         app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs + 1;
%                         app.operatorAnalysisResults.operatorNumAbnormalRBCs = app.operatorAnalysisResults.operatorNumAbnormalRBCs + 1;
%                     case 'Normal RBC'
%                         app.operatorAnalysisResults.operatorNumRBCs = app.operatorAnalysisResults.operatorNumRBCs + 1;
%                         app.operatorAnalysisResults.operatorNumNormalRBs = app.operatorAnalysisResults.operatorNumNormalRBCs + 1;
%                 end
%             end
        end

        % Value changed function: CellQtDropDown
        function CellQtDropDownValueChanged(app, event)
            app.operatorEditAnalysed.cellQtEditFlag = true;
            %             app.operatorEditAnalysed.cellQtOld = app.buttongroupvalueextract(event.OldValue.Text);
            %             app.operatorEditAnalysed.cellQtNew = app.buttongroupvalueextract(app.CellQtDropDown.Value);
        end

        % Value changed function: Abnormalityknob
        function AbnormalityknobValueChanged(app, event)
            app.operatorEditAnalysed.cellAbnormalityEditFlag = true;
%             app.operatorEditAnalysed.cellAbnormalityOld = app.buttongroupvalueextract(event.OldValue.Text);
%             app.operatorEditAnalysed.cellAbnormalityNew = app.buttongroupvalueextract(app.Abnormalityknob.Value);
        end

        % Button pushed function: optionsButton
        function optionsButtonPushed(app, event)
            app.optionsButton.Enable = 'off';
            app.H1OptionsDialogApp = H1Options(app, app.optionsvalues);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'UI Figure';
            app.CellUIAxes = uiaxes(app.UIFigure);
            title(app.CellUIAxes, 'region')
            app.CellUIAxes.XLim = [0 1];
            app.CellUIAxes.Visible = 'off';
            app.SmearUIAxes = uiaxes(app.UIFigure);
            app.SmearUIAxes.XLim = [0 1];
            app.SmearUIAxes.XTick = [];
            app.SmearUIAxes.XTickLabel = {};
            app.SmearUIAxes.YTick = [];
            app.SmearUIAxes.YTickLabel = {};
            app.SmearUIAxes.ZTick = [];
            app.SmearUIAxes.ZTickLabel = '';
            app.SmearUIAxes.Visible = 'off';
            app.knobKnobLabel = uilabel(app.UIFigure);
            app.knobKnobLabel.Visible = 'off';
            app.knobKnobLabel.Text = 'knob';
            app.Abnormalityknob = uiknob(app.UIFigure, 'discrete');
            app.Abnormalityknob.Items = {'Target', 'Spherocyte', 'Hyper', 'Hypo'};
            app.Abnormalityknob.ItemsData = {'21', '22', '23', '24'};
            app.Abnormalityknob.ValueChangedFcn = createCallbackFcn(app, @AbnormalityknobValueChanged, true);
            app.Abnormalityknob.Tooltip = {'"'};
            app.Abnormalityknob.Value = '21';
            app.CellstateButtonGroup = uibuttongroup(app.UIFigure);
            app.CellstateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CellstateButtonGroupSelectionChanged, true);
            app.CellstateButtonGroup.Title = 'Cell state';
            app.NormalRBCButton = uitogglebutton(app.CellstateButtonGroup);
            app.NormalRBCButton.Text = 'Normal RBC';
            app.NormalRBCButton.Value = true;
            app.AbnormalRBCButton = uitogglebutton(app.CellstateButtonGroup);
            app.AbnormalRBCButton.Text = 'Abnormal RBC';
            app.UnknownButton = uitogglebutton(app.CellstateButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.SegmentedRegionLabel = uilabel(app.UIFigure);
            app.SegmentedRegionLabel.Text = 'Segmented Cell';
            app.BloodSmearImageLabel = uilabel(app.UIFigure);
            app.BloodSmearImageLabel.Text = 'Blood Smear Image';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.DetectedRegionsLabel = uilabel(app.StatisticsPanel);
            app.DetectedRegionsLabel.Text = 'Detected Regions :';
            app.NormalRBCsLabel = uilabel(app.StatisticsPanel);
            app.NormalRBCsLabel.Text = 'Normal RBCs :';
            app.AbnormalRBCsLabel = uilabel(app.StatisticsPanel);
            app.AbnormalRBCsLabel.Text = 'Abnormal RBCs :';
            app.UnknownRegionsLabel = uilabel(app.StatisticsPanel);
            app.UnknownRegionsLabel.Text = 'Unknown Regions :';
            app.DetectedRegionValueLabel = uilabel(app.StatisticsPanel);
            app.DetectedRegionValueLabel.Text = '0';
            app.NormalRBCValueLabel = uilabel(app.StatisticsPanel);
            app.NormalRBCValueLabel.Text = '0';
            app.AbnormalRBCValueLabel = uilabel(app.StatisticsPanel);
            app.AbnormalRBCValueLabel.Text = '0';
            app.UnknownRegionValueLabel = uilabel(app.StatisticsPanel);
            app.UnknownRegionValueLabel.Text = '0';
            app.StartSegmentationSwitchLabel = uilabel(app.UIFigure);
            app.StartSegmentationSwitchLabel.Text = 'Start Segmentation';
            app.StartSegmentationSwitch = uiswitch(app.UIFigure, 'toggle');
            app.StartSegmentationSwitch.ItemsData = {'0', '1'};
            app.StartSegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @StartSegmentationSwitchValueChanged, true);
            app.StartSegmentationSwitch.Value = '0';
            app.OperatorDataCollectorLabel = uilabel(app.UIFigure);
            app.OperatorDataCollectorLabel.Text = 'Operator Data Collector';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.OperatorNameEditFieldLabel = uilabel(app.UIFigure);
            app.OperatorNameEditFieldLabel.Text = 'Operator Name';
            app.OperatorNameEditField = uieditfield(app.UIFigure, 'text');
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Icon = 'helpbutton.png';
            app.HelpButton.Text = '';
            app.ImageNameLabel = uilabel(app.UIFigure);
            app.ImageNameLabel.Tooltip = {'Blood smear image file name'};
            app.ImageNameLabel.Text = {'Image Name'; ''};
            app.RegionIDLabel = uilabel(app.UIFigure);
            app.RegionIDLabel.Tooltip = {'Segmented Region ID'};
            app.RegionIDLabel.Text = '0000';
            app.LoadProgressButton = uibutton(app.UIFigure, 'push');
            app.LoadProgressButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProgressButtonPushed, true);
            app.LoadProgressButton.Text = 'Load Progress';
            app.SaveProgressButton = uibutton(app.UIFigure, 'push');
            app.SaveProgressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProgressButtonPushed, true);
            app.SaveProgressButton.Text = 'Save Progress';
            app.PrevRegion = uibutton(app.UIFigure, 'push');
            app.PrevRegion.ButtonPushedFcn = createCallbackFcn(app, @PrevRegionButtonPushed, true);
            app.PrevRegion.Text = '<<';
            app.NextRegion = uibutton(app.UIFigure, 'push');
            app.NextRegion.ButtonPushedFcn = createCallbackFcn(app, @NextRegionButtonPushed, true);
            app.NextRegion.Text = '>>';
            app.messageboxLabel = uilabel(app.UIFigure);
            app.messageboxLabel.Visible = 'off';
            app.messageboxLabel.Text = 'message box';
            app.MessageBox = uitextarea(app.UIFigure);
            app.MessageBox.Editable = 'off';
            app.CellQtDropDownLabel = uilabel(app.UIFigure);
            app.CellQtDropDownLabel.Text = 'Cell Qt';
            app.CellQtDropDown = uidropdown(app.UIFigure);
            app.CellQtDropDown.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.CellQtDropDown.ValueChangedFcn = createCallbackFcn(app, @CellQtDropDownValueChanged, true);
            app.CellQtDropDown.Tooltip = {'Select the quantity of cells that you see in the image.'};
            app.CellQtDropDown.Value = '1';
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'circular');
            app.optionsButton = uibutton(app.UIFigure, 'push');
            app.optionsButton.ButtonPushedFcn = createCallbackFcn(app, @optionsButtonPushed, true);
            app.optionsButton.Text = 'options';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H1_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end