classdef RBC_ANFIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SETTINGSTab                    matlab.ui.container.Tab
        SettingsSaveButton             matlab.ui.control.Button
        SettingsLoadButton             matlab.ui.control.Button
        SettingsProfileNameEditField   matlab.ui.control.EditField
        ProfileNameEditFieldLabel      matlab.ui.control.Label
        ANFISOPTIONSPanel              matlab.ui.container.Panel
        hyperOTEditField               matlab.ui.control.NumericEditField
        hyperOTEditFieldLabel          matlab.ui.control.Label
        hypoOTEditField                matlab.ui.control.NumericEditField
        hypoOTEditFieldLabel           matlab.ui.control.Label
        spherocyteOTEditField          matlab.ui.control.NumericEditField
        spherocyteOTEditFieldLabel     matlab.ui.control.Label
        targetOTEditField              matlab.ui.control.NumericEditField
        targetOTEditFieldLabel         matlab.ui.control.Label
        abnormalityOTEditField         matlab.ui.control.NumericEditField
        abnormalityOTEditFieldLabel    matlab.ui.control.Label
        OUTPUTSTHRESHOLDLabel          matlab.ui.control.Label
        cellTypeOTEditField            matlab.ui.control.NumericEditField
        cellTypeOTEditFieldLabel       matlab.ui.control.Label
        hyperFISinputsEditField        matlab.ui.control.EditField
        hyperFISEditFieldLabel         matlab.ui.control.Label
        hypoFISinputsEditField         matlab.ui.control.EditField
        hypoFISEditFieldLabel          matlab.ui.control.Label
        spherocyteFISinputsEditField   matlab.ui.control.EditField
        spherocyteFISEditFieldLabel    matlab.ui.control.Label
        targetFISinputsEditField       matlab.ui.control.EditField
        targetFISEditFieldLabel        matlab.ui.control.Label
        abnormalityFISinputsEditField  matlab.ui.control.EditField
        abnormalityFISEditFieldLabel   matlab.ui.control.Label
        cellTypeFISinputsEditField     matlab.ui.control.EditField
        cellTypeFISEditFieldLabel      matlab.ui.control.Label
        INPUTSLabel                    matlab.ui.control.Label
        EpochNumberEditField           matlab.ui.control.NumericEditField
        EpochNumberEditFieldLabel      matlab.ui.control.Label
        ClusteringmethodDropDown       matlab.ui.control.DropDown
        ClusteringmethodDropDownLabel  matlab.ui.control.Label
        StepincreaseratioEditField     matlab.ui.control.NumericEditField
        StepincreaseratioEditFieldLabel  matlab.ui.control.Label
        StepdecreaseratioEditField     matlab.ui.control.NumericEditField
        StepdecreaseratioEditFieldLabel  matlab.ui.control.Label
        StepsizeEditField              matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        MembershipfunctionDropDown     matlab.ui.control.DropDown
        MembershipfunctionDropDownLabel  matlab.ui.control.Label
        GLCMSETTINGSPanel              matlab.ui.container.Panel
        OffsetDirectionButtonGroup     matlab.ui.container.ButtonGroup
        offset135Button                matlab.ui.control.RadioButton
        offset90Button                 matlab.ui.control.RadioButton
        offset45Button                 matlab.ui.control.RadioButton
        offset0Button                  matlab.ui.control.RadioButton
        omniButton                     matlab.ui.control.RadioButton
        OffsetNumberEditField          matlab.ui.control.NumericEditField
        OffsetNumberEditFieldLabel     matlab.ui.control.Label
        IMAGESETTINGSPanel             matlab.ui.container.Panel
        ColorLayerDropDown             matlab.ui.control.DropDown
        ColorLayerDropDownLabel        matlab.ui.control.Label
        ColorcodeDropDown              matlab.ui.control.DropDown
        ColorcodeDropDownLabel         matlab.ui.control.Label
        DESIGNDATATab                  matlab.ui.container.Tab
        InsertDataButton               matlab.ui.control.Button
        LoadedDataFilesTextArea        matlab.ui.control.TextArea
        LoadedDataFilesTextAreaLabel   matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        CheckingDataPanel              matlab.ui.container.Panel
        NumberofcellclassesforANFIScheckingLabel  matlab.ui.control.Label
        CheckingHyperNumber            matlab.ui.control.NumericEditField
        HyperEditField_2Label          matlab.ui.control.Label
        CheckingHypoNumber             matlab.ui.control.NumericEditField
        HypoEditField_2Label           matlab.ui.control.Label
        CheckingSpherocyteNumber       matlab.ui.control.NumericEditField
        SpherocyteEditField_2Label     matlab.ui.control.Label
        CheckingTargetNumber           matlab.ui.control.NumericEditField
        TargetEditField_2Label         matlab.ui.control.Label
        CheckingNormalNumber           matlab.ui.control.NumericEditField
        NormalEditField_2Label         matlab.ui.control.Label
        CheckingUnknownNumber          matlab.ui.control.NumericEditField
        UnknownEditField_2Label        matlab.ui.control.Label
        SaveCheckingDataButton         matlab.ui.control.Button
        LoadCheckingDataButton         matlab.ui.control.Button
        TrainingDataPanel              matlab.ui.container.Panel
        NumberofcellclassesforANFIStrainingLabel  matlab.ui.control.Label
        TrainingHyperNumber            matlab.ui.control.NumericEditField
        HyperEditFieldLabel            matlab.ui.control.Label
        TrainingHypoNumber             matlab.ui.control.NumericEditField
        HypoEditFieldLabel             matlab.ui.control.Label
        TrainingSpherocyteNumber       matlab.ui.control.NumericEditField
        SpherocyteEditFieldLabel       matlab.ui.control.Label
        TrainingTargetNumber           matlab.ui.control.NumericEditField
        TargetEditFieldLabel           matlab.ui.control.Label
        TrainingNormalNumber           matlab.ui.control.NumericEditField
        NormalEditFieldLabel           matlab.ui.control.Label
        TrainingUnknownNumber          matlab.ui.control.NumericEditField
        UnknownEditFieldLabel          matlab.ui.control.Label
        LoadTrainingDataButton         matlab.ui.control.Button
        SaveTrainingDataButton         matlab.ui.control.Button
        INPUTSEARCHTab                 matlab.ui.container.Tab
        inputSearchEpochNumberEditField  matlab.ui.control.NumericEditField
        InputSearchEpochNumberLabel    matlab.ui.control.Label
        hyperLamp                      matlab.ui.control.Lamp
        hyperLampLabel                 matlab.ui.control.Label
        hypoLamp                       matlab.ui.control.Lamp
        hypoLampLabel                  matlab.ui.control.Label
        spherocyteLamp                 matlab.ui.control.Lamp
        spherocyteLampLabel            matlab.ui.control.Label
        targetLamp                     matlab.ui.control.Lamp
        targetLampLabel                matlab.ui.control.Label
        abnormalityLamp                matlab.ui.control.Lamp
        abnormalityLampLabel           matlab.ui.control.Label
        cellTypeLamp                   matlab.ui.control.Lamp
        cellTypeLampLabel              matlab.ui.control.Label
        InputSearchButton              matlab.ui.control.Button
        SaveReportButton               matlab.ui.control.Button
        ANFISDESIGNRESULTSTab          matlab.ui.container.Tab
        loaddesignButton               matlab.ui.control.Button
        savedesignButton               matlab.ui.control.Button
        ANFISoutputdataTextArea        matlab.ui.control.TextArea
        ANFISoutputdataTextAreaLabel   matlab.ui.control.Label
        TrainCheckANFISButton          matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        CLASSIFICATIONTab              matlab.ui.container.Tab
        ClassifyButton                 matlab.ui.control.Button
        AnalysisResultEditField        matlab.ui.control.EditField
        AnalysisResultEditFieldLabel   matlab.ui.control.Label
        nextButton                     matlab.ui.control.Button
        previousButton                 matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
        AnalysisResultsPanel           matlab.ui.container.Panel
        LoadSmearImageButton           matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %classification variables
        classificationData = struct('image',struct('fFilePath','','smearImage',[]));
        classificationResults = struct('smearName','',...
            'numObjects',0,...
            'numRBC',0,...
            'numAbnormalRBC',0);
        
        operatorAnalysisResults = struct('operatorName',0,...       %is the main Variable and stores all final results
            'operatorNumObjects',0,...
            'operatorNumRBCs',0,...
            'operatorNumNormalRBCs',0,...
            'operatorNumAbnormalRBCs',0,...
            'operatorNumFragments',0,...
            'softwareNumObjects',0,...
            'softwareNumFragments', 0,...
            'softwareNumRBCs',0,...
            'lastAnalysedRegionRow',0,...%last analysed region row number
            'region',struct('regionID',uint16([00, 00]),...
            'position',[],...
            'mask',0,...
            'cell',0,...
            'cellRGB',0,...
            'isRBC',true,...
            'GLCM',[],...
            'operatorAnalysis', 1,...
            'operatorCellQt', 1)); %this struct does not include fragment data % Description
        % Cell types containers_final result
        DataUnknown = struct([]);
        DataNormal = struct([]);
        DataTarget = struct([]);
        DataSpherocyte = struct([]);
        DataHypo = struct([]);
        DataHyper = struct([]);
        
        %settings ans variables that is defined by user
        softwareTempVariables = struct('settingsTab',struct('profileName','unknown','image',struct('colorcode','rgb','colorLayer','green'),...
            'glcm',struct('offsetNumber',4,'offsetDirection', 'omni'),...
            'anfis',struct('clusteringMethod','GridPartition','stepSize',0.1,'stepDecreaseRatio',0.1,'stepIncreaseRatio',0.1,'membershipFunction','gbellmf', 'epochNumber',1,...
            'inputs',struct('cellTypeFIS',[], 'abnormalityFIS',[],'targetFIS',[],'spherocyteFIS',[],'hypoFIS',[],'hyperFIS',[]),...
            'outputThreshold',struct('cellTypeFIS',0.5, 'abnormalityFIS',0.5,'targetFIS',0.5,'spherocyteFIS',0.5,'hypoFIS',0.5,'hyperFIS',0.5))),...
            'processedLoadedDataNumber',struct('unknown',0,'normal',0,'target',0,'spherocyte',0,'hypo',0,'hyper',0),...
            'glcmOffsetType','omni', 'glcmOffsetNumber', 5,...
            'analysedSmearsFilePath',[],...
            'designDataTab',struct('training',struct( 'maxUnknown',0,'maxNormal',0,'maxTarget',0,'maxSpherocyte',0,'maxHypo',0,'maxHyper',0),...
            'checking',struct('maxUnknown',0,'maxNormal',0,'maxTarget',0,'maxSpherocyte',0,'maxHypo',0,'maxHyper',0),'designDataText', []),...
            'inputSearchTab',struct('epochNumber',1),...
            'directories',struct('imageTemp','D:/ANFISimageTemp/'));
        
        settings = struct('image',[],'GLCM',struct([]),'ANFIS',[],'research',struct('trailSetting',[]));
        
        %Region Type reference
        operatorRegionType = struct('normal',1,...
            'abnormal', struct('target',21,...
            'spherocyte',22,...
            'hyper',23, 'hypo',24),...
            'unknown',3); %Operator selections values
        
        %input search result container
        inputSearchReportData = struct('fisLayer',struct('cellType',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')),...
            'abnormality',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')),...
            'target',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')),...
            'spherocyte',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')),...
            'hypo',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')),...
            'hyper',struct('oneInput',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputs',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'oneInputMean',struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'twoInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'threeInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName',''),...
            'fourInputsMean', struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','')))); % Description
        ANFIStargetImage = struct('filePath','0','rgbImage', [], 'mask', [],'oneDImage', [], 'connectedComponents',[]); % Description
        
        
    end
    
    properties (Access = public)
        trainingDataTotal = struct('cellClasses',struct('unknown',struct([]),'normal',struct([]),'target',struct([]),...
            'spherocyte',struct([]),'hypo',struct([]),'hyper',struct([])),...
            'fisLayer',struct('cellType',[],'abnormality',[],'target',[],'spherocyte',[],...
            'hypo',[],'hyper',[],'variableNames',struct('inputs',{'contrast1','contrast2','contrast3','contrast4',...
            'correlation1','correlation2','correlation3','correlation4','energy1','energy2','energy3','energy4','homogeneity1','homogeneity2',...
            'homogeneity3','homogeneity4','contrastMean','correlationMean','energyMean','homogeneityMean'}))); % Description
        checkingDataTotal = struct('cellClasses',struct('unknown',struct([]),'normal',struct([]),'target',struct([]),...
            'spherocyte',struct([]),'hypo',struct([]),'hyper',struct([])),...
            'fisLayer',struct('cellType',[],'abnormality',[],'target',[],'spherocyte',[],...
            'hypo',[],'hyper',[],'variableNames',struct('inputs',{'contrast1','contrast2','contrast3','contrast4',...
            'correlation1','correlation2','correlation3','correlation4','energy1','energy2','energy3','energy4','homogeneity1','homogeneity2',...
            'homogeneity3','homogeneity4','contrastMean','correlationMean','energyMean','homogeneityMean'})));
    end
    
    methods (Access = private)
        
        function [data, result] = loadoperatoranalysis(app)
            %opens a file explorer window and gets file path for user selection.
            %returns analysed smear data
            %             temp = struct([]);
            
            [file, path] = uigetfile('*.mat', 'Select the operator analysis data file');
            if isequal(file,0)
                %                 disp('User selected Cancel')
                data = struct([]);
                result = false;
            else
                
                app.LoadedDataFilesTextArea.Value = sprintf('%s %s\n',strcat(app.LoadedDataFilesTextArea.Value{:}), file);
                app.softwareTempVariables.analysedSmearsFilePath = [app.softwareTempVariables.analysedSmearsFilePath,[path file]];
                %                 temp = load(fullfile(path,file), '-mat');
                temp = load(fullfile(path,file));
                data = temp.operatorAnalysisResults.region;
                result = true;
            end
        end
        
        function results = processanalysedsmeardata(app, data)
            % populates RBC classes container structs
            % these data would be inserted in main training and check
            %data that is the input data. It is a struct
            
            data = app.glcmcalculator(data,app.softwareTempVariables.glcmOffsetType, app.softwareTempVariables.glcmOffsetNumber);%calculates glcm according to operator selected features for glcm
            
            regionnumber = size(data, 2); %number of regions in "data"
            
            for regioni = 2:regionnumber
                %Normal RBC
                if  isequal(data(regioni).operatorAnalysis,app.operatorRegionType.normal)
                    app.DataNormal = [app.DataNormal, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.normal = app.softwareTempVariables.processedLoadedDataNumber.normal + 1;
                    %Unknown cell
                elseif isequal(data(regioni).operatorAnalysis,app.operatorRegionType.unknown)
                    app.DataUnknown = [app.DataUnknown, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.unknown = app.softwareTempVariables.processedLoadedDataNumber.unknown + 1;
                    %Abnormal target RBC
                elseif isequal(data(regioni).operatorAnalysis,app.operatorRegionType.abnormal.target)
                    app.DataTarget = [app.DataTarget, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.target = app.softwareTempVariables.processedLoadedDataNumber.target + 1;
                    %Abnormal spherocyte RBC
                elseif isequal(data(regioni).operatorAnalysis,app.operatorRegionType.abnormal.spherocyte)
                    app.DataSpherocyte = [app.DataSpherocyte, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.spherocyte = app.softwareTempVariables.processedLoadedDataNumber.spherocyte + 1;
                    %Abnormal Hypo chromic RBC
                elseif isequal(data(regioni).operatorAnalysis,app.operatorRegionType.abnormal.hypo)
                    app.DataHypo = [app.DataHypo, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.hypo = app.softwareTempVariables.processedLoadedDataNumber.hypo + 1;
                    %Abnormal Hyper chromic RBC
                elseif isequal(data(regioni).operatorAnalysis,app.operatorRegionType.abnormal.hyper)
                    app.DataHyper = [app.DataHyper, data(regioni)];
                    app.softwareTempVariables.processedLoadedDataNumber.hyper = app.softwareTempVariables.processedLoadedDataNumber.hyper + 1;
                end
            end
            results = 1;
        end
        
        function results = glcmcalculator(app, data, glcmOffsetType, glcmOffsetNumber)
            %internal
            %calculates GLCM with operator selected parameters and inserts it into "data" struct.
            regionsNumber = size(data,2);
            if ~exist('glcmOffsetNumber','var')
                glcmOffsetNumber = 5; %default value for rbcAreaThreshold
            end
            switch glcmOffsetType
                case 'omni'
                    glcmOffsetDirection = [0 glcmOffsetNumber;-glcmOffsetNumber glcmOffsetNumber;-glcmOffsetNumber 0;-glcmOffsetNumber -glcmOffsetNumber];
                case 'offset0'
                    glcmOffsetDirection = [zeros(glcmOffsetNumber,1) (1:glcmOffsetNumber)'];
                case 'offset45'
                    glcmOffsetDirection = [-(1:1:glcmOffsetNumber)' (1:1:glcmOffsetNumber)'];
                case 'offset90'
                    glcmOffsetDirection = [-(1:1:glcmOffsetNumber)' zeros(glcmOffsetNumber,1)];
                case 'offset135'
                    glcmOffsetDirection = [-(1:1:glcmOffsetNumber)' -(1:1:glcmOffsetNumber)'];
                otherwise
                    glcmOffsetDirection = [-(1:glcmOffsetNumber)' (1:1:glcmOffsetNumber)']; % if the offset type is not defined correctly, offset45 is considered
            end
            
            for regioni = 2:regionsNumber
                glcms = graycomatrix(data(regioni).cell, 'offset', glcmOffsetDirection);
                glcmStats = graycoprops(glcms,'all');
                data(regioni).GLCM = glcmStats;
                data(regioni).GLCM.contrastMean = mean(data(regioni).GLCM.Contrast);
                data(regioni).GLCM.correlationMean = mean(data(regioni).GLCM.Correlation);
                data(regioni).GLCM.energyMean = mean(data(regioni).GLCM.Energy);
                data(regioni).GLCM.homogeneityMean = mean(data(regioni).GLCM.Homogeneity);
                [data(:).glcmOffsetDirection] = deal(glcmOffsetDirection);
                [data(:).glcmOffsetType] = deal(glcmOffsetType);
            end
            
            results = data;
        end
        
        function [trainingData, checkingData] = inserttrainingcheckingdata(app)
            %This function fills main training and checking data structs from input data by the user.
            %"fisLayer" is a string (cellType, abnormality, target,spherocyte,hypo,hyper). it is used to fill data struct for each fis layer.
            %in the next step, in the beginning of ANFIS training each anfis will pick it's required data.
            
            trainingData =  struct('cellClasses',struct('unknown',struct([]),'normal',struct([]),'target',struct([]),...
                'spherocyte',struct([]),'hypo',struct([]),'hyper',struct([])),...
                'fisLayer',struct('cellType',[],'abnormality',[],'target',[],'spherocyte',[],...
                'hypo',[],'hyper',[], 'variableNames',struct('inputs',[])));
            checkingData =  struct('cellClasses',struct('unknown',struct([]),'normal',struct([]),'target',struct([]),...
                'spherocyte',struct([]),'hypo',struct([]),'hyper',struct([])),...
                'fisLayer',struct('cellType',[],'abnormality',[],'target',[],'spherocyte',[],...
                'hypo',[],'hyper',[],'variableNames',struct('inputs',[])));
            %insert training data
            trainingData.cellClasses.unknown = app.filldatastruct(app.DataUnknown, app.softwareTempVariables.designDataTab.training.maxUnknown, 'forward');
            trainingData.cellClasses.normal = app.filldatastruct(app.DataNormal, app.softwareTempVariables.designDataTab.training.maxNormal, 'forward');
            trainingData.cellClasses.target = app.filldatastruct(app.DataTarget, app.softwareTempVariables.designDataTab.training.maxTarget, 'forward');
            trainingData.cellClasses.spherocyte = app.filldatastruct(app.DataSpherocyte, app.softwareTempVariables.designDataTab.training.maxSpherocyte, 'forward');
            trainingData.cellClasses.hypo = app.filldatastruct(app.DataHypo, app.softwareTempVariables.designDataTab.training.maxHypo, 'forward');
            trainingData.cellClasses.hyper = app.filldatastruct(app.DataHyper, app.softwareTempVariables.designDataTab.training.maxHyper, 'forward');
            %insert checking data
            checkingData.cellClasses.unknown = app.filldatastruct(app.DataUnknown, app.softwareTempVariables.designDataTab.checking.maxUnknown, 'backward');
            checkingData.cellClasses.normal = app.filldatastruct(app.DataNormal, app.softwareTempVariables.designDataTab.checking.maxNormal, 'backward');
            checkingData.cellClasses.target = app.filldatastruct(app.DataTarget, app.softwareTempVariables.designDataTab.checking.maxTarget, 'backward');
            checkingData.cellClasses.spherocyte = app.filldatastruct(app.DataSpherocyte, app.softwareTempVariables.designDataTab.checking.maxSpherocyte, 'backward');
            checkingData.cellClasses.hypo = app.filldatastruct(app.DataHypo, app.softwareTempVariables.designDataTab.checking.maxHypo, 'backward');
            checkingData.cellClasses.hyper = app.filldatastruct(app.DataHyper, app.softwareTempVariables.designDataTab.checking.maxHyper, 'backward');
            
            %% collect FIS layers required data
            trainingData.fisLayer.variableNames.inputs = {'contrast1','contrast2','contrast3','contrast4',...
                'correlation1','correlation2','correlation3','correlation4','energy1','energy2','energy3','energy4','homogeneity1','homogeneity2',...
                'homogeneity3','homogeneity4','contrastMean','correlationMean','energyMean','homogeneityMean'};
            checkingData.fisLayer.variableNames.inputs ={'contrast1','contrast2','contrast3','contrast4',...
                'correlation1','correlation2','correlation3','correlation4','energy1','energy2','energy3','energy4','homogeneity1','homogeneity2',...
                'homogeneity3','homogeneity4','contrastMean','correlationMean','energyMean','homogeneityMean'};
            
            %cellType Layer (unknown 0 vs RBC 1)
            trainingData.fisLayer.cellType = app.fillfislayertrainingcheckdata(trainingData.cellClasses.unknown, 0);
            trainingData.fisLayer.cellType = [trainingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(trainingData.cellClasses.normal, 1)];
            trainingData.fisLayer.cellType = [trainingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 1)];
            trainingData.fisLayer.cellType = [trainingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 1)];
            trainingData.fisLayer.cellType = [trainingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 1)];
            trainingData.fisLayer.cellType = [trainingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 1)];
            
            checkingData.fisLayer.cellType = app.fillfislayertrainingcheckdata(checkingData.cellClasses.unknown, 0);
            checkingData.fisLayer.cellType = [checkingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(checkingData.cellClasses.normal, 1)];
            checkingData.fisLayer.cellType = [checkingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 1)];
            checkingData.fisLayer.cellType = [checkingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 1)];
            checkingData.fisLayer.cellType = [checkingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 1)];
            checkingData.fisLayer.cellType = [checkingData.fisLayer.cellType; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 1)];
            
            %abnormality Layer (normal 0 vs abnormal 1)
            trainingData.fisLayer.abnormality = app.fillfislayertrainingcheckdata(trainingData.cellClasses.normal, 0);
            trainingData.fisLayer.abnormality = [trainingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 1)];
            trainingData.fisLayer.abnormality = [trainingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 1)];
            trainingData.fisLayer.abnormality = [trainingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 1)];
            trainingData.fisLayer.abnormality = [trainingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 1)];
            
            checkingData.fisLayer.abnormality = app.fillfislayertrainingcheckdata(checkingData.cellClasses.normal, 0);
            checkingData.fisLayer.abnormality = [checkingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 1)];
            checkingData.fisLayer.abnormality = [checkingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 1)];
            checkingData.fisLayer.abnormality = [checkingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 1)];
            checkingData.fisLayer.abnormality = [checkingData.fisLayer.abnormality; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 1)];
            
            %target Layer (target 1 vs other abnormals 0)
            trainingData.fisLayer.traget = app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 1);
            trainingData.fisLayer.target = [trainingData.fisLayer.target; app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 0)];
            trainingData.fisLayer.target = [trainingData.fisLayer.target; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 0)];
            trainingData.fisLayer.target = [trainingData.fisLayer.target; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 0)];
            
            checkingData.fisLayer.traget = app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 1);
            checkingData.fisLayer.target = [checkingData.fisLayer.target; app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 0)];
            checkingData.fisLayer.target = [checkingData.fisLayer.target; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 0)];
            checkingData.fisLayer.target = [checkingData.fisLayer.target; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 0)];
            
            %spherocyte Layer (spherocyte 1 vs other abnormals 0)
            trainingData.fisLayer.spherocyte = app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 1);
            trainingData.fisLayer.spherocyte = [trainingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 0)];
            trainingData.fisLayer.spherocyte = [trainingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 0)];
            trainingData.fisLayer.spherocyte = [trainingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 0)];
            
            checkingData.fisLayer.spherocyte = app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 1);
            checkingData.fisLayer.spherocyte = [checkingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 0)];
            checkingData.fisLayer.spherocyte = [checkingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 0)];
            checkingData.fisLayer.spherocyte = [checkingData.fisLayer.spherocyte; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 0)];
            
            %hypo Layer (hypo 1 vs other abnormals 0)
            trainingData.fisLayer.hypo = app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 1);
            trainingData.fisLayer.hypo = [trainingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 0)];
            trainingData.fisLayer.hypo = [trainingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 0)];
            trainingData.fisLayer.hypo = [trainingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 0)];
            
            checkingData.fisLayer.hypo = app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 1);
            checkingData.fisLayer.hypo = [checkingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 0)];
            checkingData.fisLayer.hypo = [checkingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 0)];
            checkingData.fisLayer.hypo = [checkingData.fisLayer.hypo; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 0)];
            
            %spherocyte Layer (spherocyte 1 vs other abnormals 0)
            trainingData.fisLayer.hyper = app.fillfislayertrainingcheckdata(trainingData.cellClasses.hyper, 1);
            trainingData.fisLayer.hyper = [trainingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(trainingData.cellClasses.target, 0)];
            trainingData.fisLayer.hyper = [trainingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(trainingData.cellClasses.hypo, 0)];
            trainingData.fisLayer.hyper = [trainingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(trainingData.cellClasses.spherocyte, 0)];
            
            checkingData.fisLayer.hyper = app.fillfislayertrainingcheckdata(checkingData.cellClasses.hyper, 1);
            checkingData.fisLayer.hyper = [checkingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(checkingData.cellClasses.target, 0)];
            checkingData.fisLayer.hyper = [checkingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(checkingData.cellClasses.hypo, 0)];
            checkingData.fisLayer.hyper = [checkingData.fisLayer.hyper; app.fillfislayertrainingcheckdata(checkingData.cellClasses.spherocyte, 0)];
        end
        
        function destination = filldatastruct(app, origin, numberOfRows, direction)
            %internal
            %used in "inserttrainingcheckingdata()"
            %numberOfRows is the number of rows that should be transferred to result destination
            %direction defines the direction of transferring from origin. 'forward' from beginning or 'backward' from the end of the struct.
            %origin and destination are both structs
            destination = struct([]);
            if (size(origin,2) < numberOfRows)
                numberOfRows = size(origin,2);
            end
            switch direction
                case 'forward'
                    for regioni = 1:numberOfRows
                        destination = [destination , origin(regioni)];
                    end
                case 'backward'
                    for regioni = 1:numberOfRows
                        destination = [destination , origin(size(origin,2)-(regioni - 1))];
                    end
            end
            
            %             result = true;
        end
        
        function results = readsettingstab(app)
            %reads setting tab and injects settings to "app.softwareTempVariables.settingsTab"
            %More settings should be added according to operator App settings
            if not(app.SettingsProfileNameEditField.Value == "")
                app.softwareTempVariables.settingsTab.profileName = app.SettingsProfileNameEditField.Value;
                app.softwareTempVariables.settingsTab.image.colorcode = app.ColorcodeDropDown.Value;
                app.softwareTempVariables.settingsTab.image.colorLayer = app.ColorLayerDropDown.Value;
                
                app.softwareTempVariables.settingsTab.glcm.offsetNumber = app.OffsetNumberEditField.Value;
                
                %test
                app.softwareTempVariables.settingsTab.glcm.offsetDirection = app.OffsetDirectionButtonGroup.SelectedObject.Text;
                %                 app.OffsetDirectionButtonGroup.SelectedObject.Text
                %end test
                results = true;
            else
                msgbox('"Profile Name" field is empty!', 'Error','error');
                results = false;
            end
            
            app.softwareTempVariables.settingsTab.anfis.clusteringMethod = app.ClusteringmethodDropDown.Value;
            app.softwareTempVariables.settingsTab.anfis.stepSize = app.StepsizeEditField.Value;
            app.softwareTempVariables.settingsTab.anfis.stepDecreaseRatio = app.StepdecreaseratioEditField.Value;
            app.softwareTempVariables.settingsTab.anfis.stepIncreaseRatio = app.StepincreaseratioEditField.Value;
            app.softwareTempVariables.settingsTab.anfis.membershipFunction = app.MembershipfunctionDropDown.Value;
            app.softwareTempVariables.settingsTab.anfis.epochNumber = app.EpochNumberEditField.Value;
            app.softwareTempVariables.settingsTab.anfis.inputs.cellTypeFIS = app.textsearch4inputsnames(app.cellTypeFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.inputs.abnormalityFIS = app.textsearch4inputsnames(app.abnormalityFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.inputs.targetFIS = app.textsearch4inputsnames(app.targetFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.inputs.spherocyteFIS = app.textsearch4inputsnames(app.spherocyteFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.inputs.hypoFIS = app.textsearch4inputsnames(app.hypoFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.inputs.hyperFIS = app.textsearch4inputsnames(app.hyperFISinputsEditField.Value, app.trainingDataTotal.fisLayer.variableNames.inputs);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.cellTypeFIS = str2double(app.cellTypeOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.abnormalityFIS = str2double(app.abnormalityOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.targetFIS = str2double(app.targetOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.spherocyteFIS = str2double(app.spherocyteOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.hypoFIS = str2double(app.hypoOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.outputThreshold.hyperFIS = str2double(app.hyperOTEditField.Value);
            app.softwareTempVariables.settingsTab.anfis.inputs
        end
        
        function results = savevariable(app, v1, variableName)
            %saves input variable in desired location selected by user
            variable = v1;
            settingsFileName = strcat('ANFIS_',variableName,'_',app.softwareTempVariables.settingsTab.profileName,datestr(now,'_yyyy-mm-dd-HHMM'));
            [fileName,path] = uiputfile('*.mat','save data',settingsFileName);
            if isequal(fileName, 0)
                results = false;
            else
                save(fullfile(path,fileName),'variable');
                results = true;
            end
        end
        
        function data = fillfislayertrainingcheckdata(app, cellClassesData, value)
            %internal
            %used in "inserttrainingcheckingdata()"
            %This function builds training or checking data array with output column specific for each fis layer;
            % [contrast1 contrast2 contrast3 contrast4 correlation1 correlation2 correlation3 correlation4
            %  energy1 energy2 energy3 energy4 homogeneity1 homogeneity2 homogeneity3 homogeneity4
            %  conMean1 conMean2 conMean3 conMean4 corMean1 corMean2 corMean3 corMean4 eMean1 eMean2 eMean3 eMean4
            %  hMean1 hMean2 hMean3 hMean4 value] regionNumber*21 array; FIS output is value
            regionNumber = size(cellClassesData, 2);
            data = zeros(regionNumber,21);
            for regioni = 1:regionNumber
                data(regioni,:) = [cellClassesData(regioni).GLCM.Contrast, cellClassesData(regioni).GLCM.Correlation,...
                    cellClassesData(regioni).GLCM.Energy, cellClassesData(regioni).GLCM.Homogeneity,...
                    cellClassesData(regioni).GLCM.contrastMean, cellClassesData(regioni).GLCM.correlationMean,...
                    cellClassesData(regioni).GLCM.energyMean, cellClassesData(regioni).GLCM.homogeneityMean, value];
            end
        end
        
        function result = readdesigndatatab(app)
            
            app.softwareTempVariables.designDataTab.training.maxUnknown = app.TrainingUnknownNumber.Value;
            app.softwareTempVariables.designDataTab.training.maxNormal = app.TrainingNormalNumber.Value;
            app.softwareTempVariables.designDataTab.training.maxTarget = app.TrainingTargetNumber.Value;
            app.softwareTempVariables.designDataTab.training.maxSpherocyte = app.TrainingSpherocyteNumber.Value;
            app.softwareTempVariables.designDataTab.training.maxHypo = app.TrainingHypoNumber.Value;
            app.softwareTempVariables.designDataTab.training.maxHyper = app.TrainingHyperNumber.Value;
            
            app.softwareTempVariables.designDataTab.checking.maxUnknown = app.CheckingUnknownNumber.Value;
            app.softwareTempVariables.designDataTab.checking.maxNormal = app.CheckingNormalNumber.Value;
            app.softwareTempVariables.designDataTab.checking.maxTarget = app.CheckingTargetNumber.Value;
            app.softwareTempVariables.designDataTab.checking.maxSpherocyte = app.CheckingSpherocyteNumber.Value;
            app.softwareTempVariables.designDataTab.checking.maxHypo = app.CheckingHypoNumber.Value;
            app.softwareTempVariables.designDataTab.checking.maxHyper = app.CheckingHyperNumber.Value;
            
            result = true;
        end
        
        function [results] = inputsearch(app, in_n, trn_data, chk_data, mf_n, epoch_n, input_name, fisLayer, inputRange)
            %internal
            %used in "exhaustiveinputsearch()"
            
            %inputsearch Exhaustive search for input selection in ANFIS modeling
            %	inputsearch performs an exhaustive search on selecting 1 to 4 inputs inputNames
            %	from a set of input candidates for ANFIS modeling.
            %	inputsearch will launches C(M, N) ANFIS modeling processes if we want
            %	to select N inputs from M candidates. Therefore it might takes a
            %	long time if M is moderately large and N is about the half of M.
            %
            %	Usage:
            %	[selectedinput_index, ELAPSED_TIME] = ...
            %		inputsearch(IN_N, TRN_DATA, CHK_DATA, INPUT_NAME, MF_N, EPOCH_N)
            %
            %	selectedinput_index: index of the inputs selected by inputsearch
            %   index: index of input according to input columns order of input training or checking data. it is
            %           also according the input names order in input data structure.
            %	ELAPSED_TIME: elapsed time in input selection
            %	IN_N: number of inputs to be selected from the input candidates
            %		(This is restricted to be from 1 to 4.)
            %	TRN_DATA: original training data
            %	CHK_DATA: original checking data
            %	INPUT_NAME: input name for all input candidates
            %		(Optional, default to 'in1', 'in2', 'in3', etc.)
            %	MF_N: no. of membership function for each input
            %		(Optional, default to 2.)
            %	EPOCH_N: no. of training epochs for ANFIS
            %		(Optional, default to 1.)
            %   fisLayer is a string
            %   inputRange is 'All' or 'Means'
            
            %	Copyright 1994-2017 The MathWorks, Inc.
            
            results = struct('selectedInputIndex',[],'trnError', [],'chkError', [], 'elapsedTime', [],'index', [], 'plotImageName','');
            %             inputIndex,index,trnError,chkError, elapsedTime, errorPlot
            
            if nargin < 5
                epoch_n = 1;
            end
            if nargin < 4
                mf_n = 2;
            end
            %             if nargin == 3
            %                	input_name = 'in1';
            %                	for i = 2:size(trn_data,2)-1
            %                   		input_name = char(input_name, ['in', num2str(i)]);
            %                	end
            %             end
            if nargin < 3 && nargin ~= 0
               	error('Need at least three input arguments.');
            end
            
            
            if in_n < 1 || in_n > 4
               	error([mfilename, ...
                   	' only selects 1 to 4 input variables from all input candidates.']);
            end
            
            all_in_n = size(trn_data, 2)-1;
            
            t0 = clock;
            % ======= Training options
            %             mf_type = 'gbellmf';
            %             ss = 0.1;
            %             ss_dec_rate = 0.5;
            %             ss_inc_rate = 1.5;
            mf_type = app.softwareTempVariables.settingsTab.anfis.membershipFunction;
            ss = app.softwareTempVariables.settingsTab.anfis.stepSize;
            ss_dec_rate = app.softwareTempVariables.settingsTab.anfis.stepDecreaseRatio;
            ss_inc_rate = app.softwareTempVariables.settingsTab.anfis.stepIncreaseRatio;
            
            anfis_n = prod(1:all_in_n)/(prod(1:in_n)*prod(1:(all_in_n-in_n)));
            index = zeros(anfis_n, in_n);
            trn_error = zeros(anfis_n, 1);
            chk_error = zeros(anfis_n, 1);
            
            %             input_name = (input_name)'; %rotating input name ayyar to vertical
            
            if isequal(in_n, 1)
                % ====== Train ANFIS with different input variables
                %                 sprintf('\nTrain %d ANFIS models, each with %d inputs selected from %d candidates...\n\n',...
                %                     anfis_n, in_n, all_in_n);
                model = 1;
                
                for i=1:all_in_n
                    %                     in = deblank(input_name(i, :));
                    
                    index(model, :) = i;
                    this_trn_data = trn_data(:, [i all_in_n+1]);%trn_data(:, [(current input column) (output column)]); makes a trn data array with single input and output
                    this_chk_data = chk_data(:, [i all_in_n+1]);
                    
                    options = genfisOptions(app.softwareTempVariables.settingsTab.anfis.clusteringMethod);
                    options.NumMembershipFunctions = mf_n;
                    
                    options.InputMembershipFunctionType = mf_type;
                    in_fismat = genfis(this_trn_data(:,1:end-1),this_trn_data(:,end),options);
                    
                    [~, t_err , ~, ~, c_err] = ...
                       	anfis(this_trn_data, in_fismat, ...
                       	[epoch_n nan ss ss_dec_rate ss_inc_rate], ...
                       	[0 0 0 0], this_chk_data, 1);
                    
                    trn_error(model) = min(t_err);
                    chk_error(model) = min(c_err);
                    %                     fprintf('ANFIS model %d: %s', model, in);
                    %                     sprintf(' --> trn=%.4f,', trn_error(model));
                    %                     sprintf(' chk=%.4f', chk_error(model));
                    %                     sprintf('\n');
                    model = model+1;
                end
            elseif isequal(in_n, 2)
                % ====== Train ANFIS with different input variables
                %                 fprintf('\nTrain %d ANFIS models, each with %d inputs selected from %d candidates...\n\n',...
                %                     anfis_n, in_n, all_in_n);
                model = 1;
                for i=1:all_in_n
                    for j=i+1:all_in_n
                        %                         in1 = deblank(input_name(i, :));
                        %                         in2 = deblank(input_name(j, :));
                        index(model, :) = [i j];
                        this_trn_data = trn_data(:, [i j all_in_n+1]);
                        this_chk_data = chk_data(:, [i j all_in_n+1]);
                        options = genfisOptions(app.softwareTempVariables.settingsTab.anfis.clusteringMethod);
                        options.NumMembershipFunctions = mf_n;
                        options.InputMembershipFunctionType = mf_type;
                        in_fismat = genfis(this_trn_data(:,1:end-1),this_trn_data(:,end),options);
                        [~, t_err , ~, ~, c_err] = ...
                           	anfis(this_trn_data, in_fismat, ...
                           	[epoch_n nan ss ss_dec_rate ss_inc_rate], ...
                           	[0 0 0 0], this_chk_data, 1);
                        trn_error(model) = min(t_err);
                        chk_error(model) = min(c_err);
                        %                         fprintf('ANFIS model %d: %s %s', model, in1, in2);
                        %                         fprintf(' --> trn=%.4f,', trn_error(model));
                        %                         fprintf(' chk=%.4f', chk_error(model));
                        %                         fprintf('\n');
                        model = model+1;
                    end
                end
            elseif isequal(in_n, 3)
                % ====== Train ANFIS with different input variables
                %                 fprintf('\nTrain %d ANFIS models, each with %d inputs selected from %d candidates...\n\n',...
                %                     anfis_n, in_n, all_in_n);
                model = 1;
                for i=1:all_in_n
                    for j=i+1:all_in_n
                        for k=j+1:all_in_n
                            %                            	in1 = deblank(input_name(i, :));
                            %                            	in2 = deblank(input_name(j, :));
                            %                            	in3 = deblank(input_name(k, :));
                           	index(model, :) = [i j k];
                           	this_trn_data = trn_data(:, [i j k all_in_n+1]);
                           	this_chk_data = chk_data(:, [i j k all_in_n+1]);
                            options = genfisOptions(app.softwareTempVariables.settingsTab.anfis.clusteringMethod);
                            options.NumMembershipFunctions = mf_n;
                            options.InputMembershipFunctionType = mf_type;
                            in_fismat = genfis(this_trn_data(:,1:end-1),this_trn_data(:,end),options);
                           	[~, t_err , ~, ~, c_err] = ...
                             	  anfis(this_trn_data, in_fismat, ...
                             	  [epoch_n nan ss ss_dec_rate ss_inc_rate], ...
                             	  [0 0 0 0], this_chk_data, 1);
                           	trn_error(model) = min(t_err);
                           	chk_error(model) = min(c_err);
                            %                            	fprintf('ANFIS model %d: %s %s %s', model, in1, in2, in3);
                            %                             fprintf(' --> trn=%.4f,', trn_error(model));
                            %                             fprintf(' chk=%.4f', chk_error(model));
                            %                             fprintf('\n');
                           	model = model+1;
                        end
                    end
                end
            elseif isequal(in_n, 4)
                % ====== Train ANFIS with different input variables
                %                 fprintf('\nTrain %d ANFIS models, each with %d inputs selected from %d candidates...\n\n',...
                %                     anfis_n, in_n, all_in_n);
                model = 1;
                for i=1:all_in_n
                    for j=i+1:all_in_n
                        for k=j+1:all_in_n
                            for l=k+1:all_in_n
                                %                              	  in1 = deblank(input_name(i, :));
                                %                              	  in2 = deblank(input_name(j, :));
                                %                              	  in3 = deblank(input_name(k, :));
                                %                              	  in4 = deblank(input_name(l, :));
                             	  index(model, :) = [i j k l];
                             	  this_trn_data = trn_data(:, [i j k l all_in_n+1]);
                             	  this_chk_data = chk_data(:, [i j k l all_in_n+1]);
                                options = genfisOptions(app.softwareTempVariables.settingsTab.anfis.clusteringMethod);
                                options.NumMembershipFunctions = mf_n;
                                options.InputMembershipFunctionType = mf_type;
                                in_fismat = genfis(this_trn_data(:,1:end-1),this_trn_data(:,end),options);
                             	  [~, t_err , ~, ~, c_err] = ...
                               	    anfis(this_trn_data, in_fismat, ...
                               	    [epoch_n nan ss ss_dec_rate ss_inc_rate], ...
                               	    [0 0 0 0], this_chk_data, 1);
                                %                              	  fprintf('ANFIS model %d: %s %s %s %s', model, in1, in2, in3, in4);
                                %                                 fprintf(' --> trn=%.4f,', trn_error(model));
                                %                                 fprintf(' chk=%.4f', chk_error(model));
                                %                                 fprintf('\n');
                             	  trn_error(model) = min(t_err);
                             	  chk_error(model) = min(c_err);
                             	  model = model+1;
                            end
                        end
                    end
                end
            end
            % ====== Generate selectedinput_index
            [~,b] = min(trn_error);
            results.selectedInputIndex = index(b,:);
            
            results.elapsedTime = etime(clock, t0);
            
            % ====== The following is for plotting
            % ====== Reordering according to training error
            [~,b] = sort(trn_error);
            %b = flipud(b);		% List according to decreasing trn error
            trn_error = trn_error(b);
            chk_error = chk_error(b);
            index = index(b, :);
            results.trnError = trn_error;
            results.chkError = chk_error;
            results.index = index;
            % ====== Display training and checking errors
            figTitle = [fisLayer,' ANFIS Input Selection: Select ', num2str(in_n), ...
               	' inputs from ', num2str(all_in_n), ' candidates.',num2str(anfis_n), ' FIS generated.' ];
            figH = uifigure('Name',figTitle, 'WindowState', 'maximized','Resize','on','AutoResizeChildren', 'on', 'Visible', 'off');
            figH.Position = [20 40 1300 650];
            researchAxes = uiaxes(figH, 'NextPlot','replaceall');
            if anfis_n >= 51
                anfis_n = 50 ; % for plot readability only first 50 anfis errors will be plotted.
            end
            %             figH = findobj(0, 'name', figTitle);
            %             if isempty(figH)
            %                	figH = app.uifigure(...
            %                   		'Name', figTitle, ...
            %                     'WindowStyle','normal', ...
            %                   		'NumberTitle', 'off', 'Visible','off'); %#ok<NASGU>
            %             else
            %                	set(0, 'currentfig', figH);
            %             end
            
            x = (1:anfis_n)';
            %             UIAX = app.UIAxes2(figH);
            %             subplot(211);
            
            tmp = x(:, ones(1, 3))';
            X = tmp(:);
            tmp = [zeros(anfis_n, 1) max(trn_error(1:anfis_n,:), chk_error(1:anfis_n,:)) nan*ones(anfis_n, 1)]';
            Y = tmp(:);
            plot(researchAxes ,x, trn_error(1:anfis_n,:), '-', x, chk_error(1:anfis_n,:), '-', x, trn_error(1:anfis_n,:), 'o', x, chk_error(1:anfis_n,:), '*',X, Y, 'g');
            %             hold on; plot(app.UIAxes2,X, Y, 'g'); hold off;
            if anfis_n == 1
                XLlim = 0;
                XHlim = 2;
            else
                XLlim = 1;
                XHlim = anfis_n;
            end
            researchAxes.XLim = [XLlim XHlim];
            researchAxes.YLim = [-inf inf];
            %             uiaxes([1 anfis_n -inf inf]);
            %             app.UIAxes2.XTickLabel = [];
            %             set(gca, 'xticklabel', []);
            
            % ====== Add text of input variables
            if XHlim == 2
                researchAxes.XTickLabelRotation = 0;
            else
                researchAxes.XTickLabelRotation = 90;
            end
            %             researchAxes.XTickLabelRotation = 90;
            researchAxes.TickDir = 'out';
            researchAxes.XTickMode = 'auto';
            researchAxes.Position = [researchAxes.Position(1:2) [figH.Position(3:4) - 2*researchAxes.Position(1:2)]];
            %             app.UIAxes2.XMinorTick = 'on';
            xtlabel = cell(1,anfis_n);
            if in_n == 1
                for k = 1:anfis_n
                    xtlabel(k) = input_name(index(k));
                    %                     app.UIAxes2.XLabel.String = input_name;
                    %                     text(x(k), -0.3, deblank(input_name(index(k,1), :)));
                    %                     text(x(k), -0.3, deblank(input_name(index(k))));
                end
            elseif in_n == 2
                for k = 1:anfis_n
                    xtlabel(k) = strcat(input_name(index(k,1)),', ',input_name(index(k,2)));
                    %                    	text(x(k), -0.3, ...
                    %                        	[deblank(input_name(index(k,1), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,2), :))]);
                end
            elseif in_n == 3
                for k = 1:anfis_n
                    xtlabel(k) = strcat(input_name(index(k,1)),', ',input_name(index(k,2)),', ',input_name(index(k,3)));
                    %                    	text(x(k), -0.3, ...
                    %                        	[deblank(input_name(index(k,1), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,2), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,3), :))]);
                end
            elseif in_n == 4
                for k = 1:anfis_n
                    xtlabel(k) = strcat(input_name(index(k,1)),', ',input_name(index(k,2)),', ',input_name(index(k,3)),', ',input_name(index(k,4)));
                    %                    	text(x(k), -0.3, ...
                    %                        	[deblank(input_name(index(k,1), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,2), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,3), :)) ' ' ...
                    %                       	 deblank(input_name(index(k,4), :))]);
                end
            end
            researchAxes.XTickLabelMode = 'manual';
            %             if XHlim == 2
            %                 researchAxes.XTick = XLlim:1:XHlim;
            %             else
            %                 researchAxes.XTick = 1:1:anfis_n;
            %             end
            
            researchAxes.XTick = 1:1:anfis_n;
            researchAxes.XTickLabel = xtlabel;
            %             researchAxes.XTickLabelMode = 'manual';
            %             h = findobj(gcf, 'type', 'text');
            %             set(h, 'rot', 90, 'fontsize', 10, 'hori', 'right');
            %             drawnow
            researchAxes.Title.String ='Training (Circles) and Checking (Asterisks) Errors';
            researchAxes.TitleFontSizeMultiplier = 1;
            %             title('Training (Circles) and Checking (Asterisks) Errors','fontsize',10);
            researchAxes.YLabel.String = 'RMS Errors';
            researchAxes.YLabel.FontSize = 10;
            %             ylabel('RMS Errors','fontsize',10);
            
            %== plot file name
            %             imageFileName = inputsearchplotsave(trn_error, chk_error, index, input_name, app.softwareTempVariables.directories.imageTemp, in_n, inputRange, fisLayer);
            imageFileName = strcat(fisLayer,inputRange,num2str(in_n));
            %             print imageFileName '-dpng';
            inputsearchplotsave(researchAxes,app.softwareTempVariables.directories.imageTemp ,imageFileName,x,trn_error(1:anfis_n,:),chk_error(1:anfis_n,:),X,Y, figTitle);
            results.plotImageName = imageFileName;
            %             inputSearchData = results
            close(figH);
        end
        
        function reportData = exhaustiveinputsearch(app, trainingData, checkingData)
            %Does exhustive search for inputs in all layers and examins many inputs in each layer. it uses inputsearch() in each layer.
            %trainingData is a struct
            %checkingData is a struct
            reportData = struct('fisLayer',struct('cellType',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])),...
                'abnormality',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])),...
                'target',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])),...
                'spherocyte',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])),...
                'hypo',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])),...
                'hyper',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([])))); % Description
            
            layer = {'cellType','abnormality','target','spherocyte','hypo','hyper'};
            inputMethod = {'oneInput','twoInputs','oneInputMean','twoInputsMean','threeInputsMean','fourInputsMean'};
            
            for layeri = 1:size(layer,2)
                app.(strcat(layer{layeri},'Lamp')).Color = 'y';
                drawnow;
                for inputMethodi = 1:2
                    reportData.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}) = ...
                        app.inputsearch(inputMethodi, trainingData.fisLayer.(layer{layeri}), checkingData.fisLayer.(layer{layeri}), 2, app.softwareTempVariables.inputSearchTab.epochNumber, checkingData.fisLayer.variableNames.inputs, layer{layeri}, 'All');
                end
                
                for inputMethodi = 3:size(inputMethod,2)
                    reportData.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}) =...
                        app.inputsearch((inputMethodi - 2), trainingData.fisLayer.(layer{layeri})(:,17:21), checkingData.fisLayer.(layer{layeri})(:,17:21), 2, app.softwareTempVariables.inputSearchTab.epochNumber, checkingData.fisLayer.variableNames.inputs(17:20), layer{layeri}, 'Means');
                end
                app.(strcat(layer{layeri},'Lamp')).Color = 'g';
                drawnow;
            end
            app.SaveReportButton.Enable = 'on';
            
            
            %             %=====cellType layer search for inputs
            %             %search for one input
            %
            %             app.cellTypeLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.cellType.oneInput = app.inputsearch(1, trainingData.fisLayer.cellType, checkingData.fisLayer.cellType, 2, 1, checkingData.fisLayer.variableNames.inputs, 'cellType', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.cellType.twoInputs = app.inputsearch(2, trainingData.fisLayer.cellType, checkingData.fisLayer.cellType, 2, 1, checkingData.fisLayer.variableNames.inputs, 'cellType', 'All');
            %             %             two2Index = reportData.fisLayer.cellType.twoInputs.index
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.cellType.oneInputMean = app.inputsearch(1, trainingData.fisLayer.cellType(:,17:21), checkingData.fisLayer.cellType(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'cellType', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.cellType.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.cellType(:,17:21), checkingData.fisLayer.cellType(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'cellType', 'Means');
            %             %             twoMeanIndex = reportData.fisLayer.cellType.twoInputsMean.index
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.cellType.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.cellType(:,17:21), checkingData.fisLayer.cellType(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'cellType', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.cellType.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.cellType(:,17:21), checkingData.fisLayer.cellType(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'cellType', 'Means');
            %             %             fourMeanIndex = reportData.fisLayer.cellType.fourInputsMean.index
            %             %report results
            %             app.cellTypeLamp.Color = 'g';
            %
            %             %=====abnormality layer for inputs
            %             app.abnormalityLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.abnormality.oneInput = app.inputsearch(1, trainingData.fisLayer.abnormality, checkingData.fisLayer.abnormality, 2, 1, checkingData.fisLayer.variableNames.inputs, 'abnormality', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.abnormality.twoInputs = app.inputsearch(2, trainingData.fisLayer.abnormality, checkingData.fisLayer.abnormality, 2, 1, checkingData.fisLayer.variableNames.inputs, 'abnormality', 'All');
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.abnormality.oneInputMean = app.inputsearch(1, trainingData.fisLayer.abnormality(:,17:21), checkingData.fisLayer.abnormality(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'abnormality', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.abnormality.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.abnormality(:,17:21), checkingData.fisLayer.abnormality(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'abnormality', 'Means');
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.abnormality.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.abnormality(:,17:21), checkingData.fisLayer.abnormality(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'abnormality', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.abnormality.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.abnormality(:,17:21), checkingData.fisLayer.abnormality(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'abnormality', 'Means');
            %             %report results
            %             app.abnormalityLamp.Color = 'g';
            %
            %             %=====target fis input search
            %             app.targetLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.target.oneInput = app.inputsearch(1, trainingData.fisLayer.target, checkingData.fisLayer.target, 2, 1, checkingData.fisLayer.variableNames.inputs, 'target', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.target.twoInputs = app.inputsearch(2, trainingData.fisLayer.target, checkingData.fisLayer.target, 2, 1, checkingData.fisLayer.variableNames.inputs, 'target', 'All');
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.target.oneInputMean = app.inputsearch(1, trainingData.fisLayer.target(:,17:21), checkingData.fisLayer.target(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'target', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.target.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.target(:,17:21), checkingData.fisLayer.target(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'target', 'Means');
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.target.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.target(:,17:21), checkingData.fisLayer.target(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'target', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.target.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.target(:,17:21), checkingData.fisLayer.target(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'target', 'Means');
            %             %report results
            %             app.targetLamp.Color = 'g';
            %
            %             %=====spherocyte fis input search
            %             app.spherocyteLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.spherocyte.oneInput = app.inputsearch(1, trainingData.fisLayer.spherocyte, checkingData.fisLayer.spherocyte, 2, 1, checkingData.fisLayer.variableNames.inputs, 'spherocyte', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.spherocyte.twoInputs = app.inputsearch(2, trainingData.fisLayer.spherocyte, checkingData.fisLayer.spherocyte, 2, 1, checkingData.fisLayer.variableNames.inputs, 'spherocyte', 'All');
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.spherocyte.oneInputMean = app.inputsearch(1, trainingData.fisLayer.spherocyte(:,17:21), checkingData.fisLayer.spherocyte(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'spherocyte', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.spherocyte.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.spherocyte(:,17:21), checkingData.fisLayer.spherocyte(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'spherocyte', 'Means');
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.spherocyte.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.spherocyte(:,17:21), checkingData.fisLayer.spherocyte(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'spherocyte', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.spherocyte.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.spherocyte(:,17:21), checkingData.fisLayer.spherocyte(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'spherocyte', 'Means');
            %             %report results
            %             app.spherocyteLamp.Color = 'g';
            %
            %             %=====hypo fis input search
            %             app.hypoLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.hypo.oneInput = app.inputsearch(1, trainingData.fisLayer.hypo, checkingData.fisLayer.hypo, 2, 1, checkingData.fisLayer.variableNames.inputs, 'hypo', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.hypo.twoInputs = app.inputsearch(2, trainingData.fisLayer.hypo, checkingData.fisLayer.hypo, 2, 1, checkingData.fisLayer.variableNames.inputs, 'hypo', 'All');
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.hypo.oneInputMean = app.inputsearch(1, trainingData.fisLayer.hypo(:,17:21), checkingData.fisLayer.hypo(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hypo', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.hypo.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.hypo(:,17:21), checkingData.fisLayer.hypo(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hypo', 'Means');
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.hypo.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.hypo(:,17:21), checkingData.fisLayer.hypo(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hypo', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.hypo.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.hypo(:,17:21), checkingData.fisLayer.hypo(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hypo', 'Means');
            %             %report results
            %             app.hypoLamp.Color = 'g';
            %
            %             %=====hyper fis input search
            %             app.hyperLamp.Color = 'y';
            %             drawnow;
            %             reportData.fisLayer.hyper.oneInput = app.inputsearch(1, trainingData.fisLayer.hyper, checkingData.fisLayer.hyper, 2, 1, checkingData.fisLayer.variableNames.inputs, 'hyper', 'All');
            %             %search for 2 inputs
            %             reportData.fisLayer.hyper.twoInputs = app.inputsearch(2, trainingData.fisLayer.hyper, checkingData.fisLayer.hyper, 2, 1, checkingData.fisLayer.variableNames.inputs, 'hyper', 'All');
            %             %search for 1 inputs between Means
            %             reportData.fisLayer.hyper.oneInputMean = app.inputsearch(1, trainingData.fisLayer.hyper(:,17:21), checkingData.fisLayer.hyper(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hyper', 'Means');
            %             %search for 2 inputs between Means
            %             reportData.fisLayer.hyper.twoInputsMean = app.inputsearch(2, trainingData.fisLayer.hyper(:,17:21), checkingData.fisLayer.hyper(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hyper', 'Means');
            %             %search for 3 inputs between Means
            %             reportData.fisLayer.hyper.threeInputsMean = app.inputsearch(3, trainingData.fisLayer.hyper(:,17:21), checkingData.fisLayer.hyper(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hyper', 'Means');
            %             %search for 4 inputs between Means
            %             reportData.fisLayer.hyper.fourInputsMean = app.inputsearch(4, trainingData.fisLayer.hyper(:,17:21), checkingData.fisLayer.hyper(:,17:21), 2, 1, checkingData.fisLayer.variableNames.inputs(17:20), 'hyper', 'Means');
            %             %report results
            %             app.hyperLamp.Color = 'g';
            %             drawnow;
        end
        
        function results = reportinputsearchresults(app, inputSearchDataStruct, inputNames, authorName, filePathName)
            %reports exhaustiveinputsearch() results in a document.
            
            import mlreportgen.report.*;
            import mlreportgen.dom.*;
            
            reportContainer  = Document(filePathName,'docx','D:\ANFISimageTemp\ExhaustiveInputSearchTemplate.dotx');
            open(reportContainer);
            %Title Page
            numberOfInputs = num2str(size(inputNames,2));
            moveToNextHole(reportContainer);
            if strcmp(reportContainer.CurrentHoleId,'inputNumber')
                append(reportContainer, numberOfInputs);
            end
            moveToNextHole(reportContainer);
            append(reportContainer, authorName);
            moveToNextHole(reportContainer);
            append(reportContainer, string(datetime('now','TimeZone','local','Format','MMM dd, yyyy HH:mm:ss')));
            %intrudoction
            moveToNextHole(reportContainer);
            
            if ~strcmp(reportContainer.CurrentHoleId,'intruduction')
                textObj = "Lorem ipsum dolor sit amet, consectetur adipiscing elit, sed do eiusmod tempor incididunt ut labore et dolore magna aliqua. Ut enim blandit volutpat maecenas volutpat blandit. Libero nunc consequat interdum varius sit. Quisque non tellus orci ac auctor augue. In vitae turpis massa sed. Sed cras ornare arcu dui vivamus. Aliquam ultrices sagittis orci a scelerisque purus semper. Risus at ultrices mi tempus imperdiet nulla malesuada. Eget nullam non nisi est sit. At consectetur lorem donec massa sapien faucibus et. Nunc consequat interdum varius sit amet mattis vulputate. Imperdiet dui accumsan sit amet nulla facilisi morbi tempus. Malesuada nunc vel risus commodo.";
                append(reportContainer, textObj);
            end
            %== Design settings
            %Image Processing settings
            moveToNextHole(reportContainer);
            textObj1 = Text(sprintf('Image color code:  %s',app.softwareTempVariables.settingsTab.image.colorcode));
            textObj2 = Text(sprintf('Image color layer:  %s',app.softwareTempVariables.settingsTab.image.colorLayer));
            append(reportContainer,{textObj1,textObj2});
            %GLCM settings
            moveToNextHole(reportContainer);
            textObj1 = sprintf('Offset Number:  %d',app.softwareTempVariables.settingsTab.glcm.offsetNumber);
            textObj2 = sprintf('Offset Direction:  %s', app.softwareTempVariables.settingsTab.glcm.offsetDirection);
            append(reportContainer,{textObj1,textObj2});
            %ANFIS settings
            moveToNextHole(reportContainer);
            textObj1 = sprintf('Clustering Method:  %s',app.softwareTempVariables.settingsTab.anfis.clusteringMethod);
            textObj2 = sprintf('Step Size:  %4.2f',app.softwareTempVariables.settingsTab.anfis.stepSize);
            textObj3 = sprintf('Step Decrease Ratio:  %4.2f',app.softwareTempVariables.settingsTab.anfis.stepDecreaseRatio);
            append(reportContainer,{textObj1,textObj2,textObj3});
            %==main loop
            layer = {'cellType','abnormality','target','spherocyte','hypo','hyper'};
            inputMethod = {'oneInput','twoInputs','oneInputMean','twoInputsMean','threeInputsMean','fourInputsMean'};
            modelNum = [20,20,4,6,4,1];%number of models to show thair results
            for layeri = 1:size(layer,2)
                for inputMethodi = 1:2
                    inputs = strings(modelNum(inputMethodi),1);
                    titletext = strcat('Trained',' ', string(size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).trnError,1)),...
                        ' ANFIS models, each with',' ', string(size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index,2)) ,...
                        ' inputs selected from',' ',string(size(inputNames,2)),' candidates...');
                    textObjects = cell(1,modelNum(inputMethodi));
                    for modeli = 1:modelNum(inputMethodi)
                        for inputi = 1:size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index,2)
                            inputs(modeli) = strcat(inputs(modeli),',',inputNames(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index(modeli,inputi)));
                        end
                        textObjects(1,modeli) = {sprintf('ANFIS Model %d : %s --> training Error= %4.6f, checking Error= %4.6f',...
                            modeli,inputs(modeli),inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).trnError(modeli),...
                            inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).chkError(modeli))};
                    end
                    %build image object
                    imagePath = strcat(app.softwareTempVariables.directories.imageTemp,inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).plotImageName,'.png');
                    imageObj = Image(imagePath);
                    imageObj.Style = {ScaleToFit};
                    captionTextObj =strcat(layer{layeri},' ANFIS_ selecting',' ',inputMethod{inputMethodi});
                    %append to report container
                    moveToNextHole(reportContainer);
                    append(reportContainer,titletext);
                    moveToNextHole(reportContainer);
                    append(reportContainer,textObjects);
                    moveToNextHole(reportContainer);
                    append(reportContainer,imageObj);
                    moveToNextHole(reportContainer);
                    append(reportContainer,captionTextObj);
                end
                
                inputNamesMeans = inputNames(:,17:20);
                for inputMethodi = 3:size(inputMethod,2)
                    inputs = strings(modelNum(inputMethodi),1);
                    titletext = strcat('Trained',' ', string(size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).trnError,1)),...
                        'ANFIS models, each with',' ', string(size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index,2)) ,...
                        'inputs selected from',' ',string(size(inputNamesMeans,2)),' candidates...');
                    textObjects = cell(1,modelNum(inputMethodi));
                    for modeli = 1:modelNum(inputMethodi)
                        for inputi = 1:size(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index,2)
                            inputs(modeli) = strcat(inputs(modeli),',',inputNamesMeans(inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).index(modeli,inputi)));
                        end
                        textObjects(1,modeli) = {sprintf('ANFIS Model %d : %s --> training Error= %4.6f, checking Error= %4.6f',...
                            modeli,inputs(modeli),inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).trnError(modeli),...
                            inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).chkError(modeli))};
                    end
                    %build image object
                    imagePath = strcat(app.softwareTempVariables.directories.imageTemp,inputSearchDataStruct.fisLayer.(layer{layeri}).(inputMethod{inputMethodi}).plotImageName,'.png');
                    imageObj = Image(imagePath);
                    imageObj.Style = {ScaleToFit};
                    captionTextObj =strcat(layer{layeri},' ANFIS_ selecting',' ',inputMethod{inputMethodi});
                    %append to report container
                    moveToNextHole(reportContainer);
                    append(reportContainer,titletext);
                    moveToNextHole(reportContainer);
                    append(reportContainer,textObjects);
                    moveToNextHole(reportContainer);
                    append(reportContainer,imageObj);
                    moveToNextHole(reportContainer);
                    append(reportContainer,captionTextObj);
                end
            end
            close(reportContainer);
            results = true;
        end
        
        function results = inputSearchPlot(app, inputSearchDataStruct, inputNames, fisLayerName)
            %NOT USED . an external function called "inputSearchPlotsave()" is being used.
            %'cellType',struct('oneInput',struct([]), 'twoInputs',struct([]),'oneInputMean',struct([]), 'twoInputsMean', struct([]), 'threeInputsMean', struct([]), 'fourInputsMean', struct([]))
            
            % ====== The following is for plotting
            all_in_n %input names size
            anfis_n  %trn_error number of rows
            index %vertical array with row number equal to number of inputs. 1 to 20
            trn_error
            chk_error
            input_name
            
            % ====== Reordering according to training error
            [~,b] = sort(trn_error);
            %b = flipud(b);		% List according to decreasing trn error
            trn_error = trn_error(b);
            chk_error = chk_error(b);
            index = index(b, :);
            % ====== Display training and checking errors
            figTitle = ['ANFIS Input Selection: Select ', num2str(in_n), ...
               	' inputs from ', num2str(all_in_n), ' candidates'];
            fileName = [fisLayerName,' ',num2str(in_n), ' inputs from ', num2str(all_in_n)];%I should add type of inputs (Means?)
            figH = findobj(0, 'name', figTitle);
            if isempty(figH)
               	figH = figure(...
                  		'Name', figTitle, ...
                    'WindowStyle','normal', ...
                  		'NumberTitle', 'off', 'Visible','off'); %#ok<NASGU>
            else
               	set(0, 'currentfig', figH);
            end
            
            x = (1:anfis_n)';
            subplot(211);
            plot(x, trn_error, '-', x, chk_error, '-', ...
                x, trn_error, 'o', x, chk_error, '*');
            tmp = x(:, ones(1, 3))';
            X = tmp(:);
            tmp = [zeros(anfis_n, 1) max(trn_error, chk_error) nan*ones(anfis_n, 1)]';
            Y = tmp(:);
            hold on; plot(X, Y, 'g'); hold off;
            axis([1 anfis_n -inf inf]);
            set(gca, 'xticklabel', []);
            
            % ====== Add text of input variables
            if in_n == 1
                for k = 1:anfis_n
                   	text(x(k), -0.3, deblank(input_name(index(k,1), :)));
                end
            elseif in_n == 2
                for k = 1:anfis_n
                   	text(x(k), -0.3, ...
                       	[deblank(input_name(index(k,1), :)) ' ' ...
                      	 deblank(input_name(index(k,2), :))]);
                end
            elseif in_n == 3
                for k = 1:anfis_n
                   	text(x(k), -0.3, ...
                       	[deblank(input_name(index(k,1), :)) ' ' ...
                      	 deblank(input_name(index(k,2), :)) ' ' ...
                      	 deblank(input_name(index(k,3), :))]);
                end
            elseif in_n == 4
                for k = 1:anfis_n
                   	text(x(k), -0.3, ...
                       	[deblank(input_name(index(k,1), :)) ' ' ...
                      	 deblank(input_name(index(k,2), :)) ' ' ...
                      	 deblank(input_name(index(k,3), :)) ' ' ...
                      	 deblank(input_name(index(k,4), :))]);
                end
            end
            
            h = findobj(gcf, 'type', 'text');
            set(h, 'rot', 90, 'fontsize', 10, 'hori', 'right');
            drawnow
            title('Training (Circles) and Checking (Asterisks) Errors','fontsize',10);
            ylabel('RMS Errors','fontsize',10);
            print -dpng
            
            
        end
        
        function results = readinputsearchtab(app)
            app.softwareTempVariables.inputSearchTab.epochNumber = app.inputSearchEpochNumberEditField.Value;
            results = true;
        end
        
        function [results] = textsearch4inputsnames(app, text, inputNames)
            %internal
            %gets input names from edit field as string and outputs correct input names indices
            %inputNames is a cell array
            %text is string
            if ~isempty(text)
                inputs = textscan(lower(text), '%s', 'delimiter', {',',' ','/','\','&','-','_','+','*','.'});
                inputs = inputs{1};
                inputIndex = [];
                nonInputN = 0;
                for numi = 1:size(inputs,1)
                    temp = find(strcmp(inputNames, inputs{numi}));
                    if (size(temp,1) ~= 0 && size(temp,2) ~= 0)
                        inputIndex =[inputIndex, temp];
                    else
                        nonInputN = nonInputN +1;
                    end
                end
                results = inputIndex;
            else
                results = [];
            end
            
            
        end
        
        function results = fillinputnames(app, inputIndices, inputNames)
            %fills input names from input struct to FISinputsEditFields
            %inputs=struct('cellTypeFIS',[], 'abnormalityFIS',[],'targetFIS',[],'spherocyteFIS',[],'hypoFIS',[],'hyperFIS',[])
            inputs = {'cellTypeFIS', 'abnormalityFIS','targetFIS','spherocyteFIS','hypoFIS','hyperFIS'};
            for FISi = 1: size(inputs,2)
                inputstr = '';
                for inputi = 1:size(inputIndices.(inputs{FISi}),2)
                    inputstr = strcat(inputstr ,inputNames{inputIndices.(inputs{FISi})(inputi)},',');
                end
                fieldNamestr = strcat(inputs{FISi},'inputsEditField');
                app.(fieldNamestr).Value = inputstr(1:end-1);
            end
            drawnow;
            results = true;
        end
        
        %%ANFIS Design Results Tab functions
        function results = trainandcheckanfis(app, anfisSettings, selectedInputIndexes,trainingData, checkingData, outputThresholds, inputNames)
            %inputs:
            %anfisSettings: softwareTempVariables.settingsTab.anfis
            %selectedInputIndexes : softwareTempVariables.settingsTab.anfis.inputs
            %outputs: results is a structure containing fis data for all layers
            
            % build output structure
            results = struct('cellType',struct('fis',[],'trainingError', 0, 'checkingError', 0),...
                'abnormality',struct('fis',[],'trainingError', 0, 'checkingError', 0),...
                'target',struct('fis',[],'trainingError', 0, 'checkingError', 0),...
                'spherocyte',struct('fis',[],'trainingError', 0, 'checkingError', 0),...
                'hypo',struct('fis',[],'trainingError', 0, 'checkingError', 0),...
                'hyper',struct('fis',[],'trainingError', 0, 'checkingError', 0));
            %T&C cellType layer
            [results.cellType.fis, results.cellType.trainingError, results.cellType.checkingError] = app.layeranfis(selectedInputIndexes.cellTypeFIS, trainingData, checkingData,anfisSettings);
            %T&C abnormality layer
            [results.abnormality.fis, results.abnormality.trainingError, results.abnormality.checkingError] = app.layeranfis(selectedInputIndexes.abnormalityFIS, trainingData, checkingData,anfisSettings);
            %T&C target layer
            [results.target.fis, results.target.trainingError, results.target.checkingError] = app.layeranfis(selectedInputIndexes.targetFIS, trainingData, checkingData,anfisSettings);
            %T&C spherocyte layer
            [results.spherocyte.fis, results.spherocyte.trainingError, results.spherocyte.checkingError] = app.layeranfis(selectedInputIndexes.spherocyteFIS, trainingData, checkingData,anfisSettings);
            %T&C hypo layer
            [results.hypo.fis, results.hypo.trainingError, results.hypo.checkingError] = app.layeranfis(selectedInputIndexes.hypoFIS, trainingData, checkingData,anfisSettings);
            %T&C hyper layer
            [results.hyper.fis, results.hyper.trainingError, results.hyper.checkingError] = app.layeranfis(selectedInputIndexes.hyperFIS, trainingData, checkingData,anfisSettings);
        end
        
        function [fis, trainingError, checkingError] = layeranfis(app, selectedInputIndex, trainingData, checkingData, anfisOpts)
            %internal
            %used in "trainandcheckanfis()"
            %inputs: selectedInputs, trainingData, checkingData, anfisOptions
            %       training and checking data are complete set.
            %outputs: fis, trainingError, checkingError
            %'anfis',struct('clusteringMethod','GridPartition','stepSize',0.1,'stepDecreaseRatio',0.1,'stepIncreaseRatio',0.1,'membershipFunction','gbellmf', 'epochNumber',1,
            
            %1- check number of inputs by counting input index array
            dataColumns = [selectedInputIndex, size(trainingData,2)];
            %2- Build training data matrix
            this_trn_data = trainingData(:, dataColumns);
            %3- build checking data matrix
            this_chk_data = checkingData(:, dataColumns);
            %4- train and check fis by ANFIS
            options = genfisOptions(anfisOptions.clusteringMethod);
            %options.NumMembershipFunctions = mf_n;
            options.InputMembershipFunctionType = anfisOptions.membershipFunction;
            in_fismat = genfis(this_trn_data(:,1:end-1),this_trn_data(:,end),options);
            anfisOpt = anfisOptions('InitialFIS', in_fismat,'EpochNumber', anfisOpts.epochNumber,...
                'InitialStepSize', anfisOpts.stepSize, 'StepSizeDecreaseRate',anfisOpts.stepDecreaseRatio,...
                'StepSizeIncreaseRate',anfisOpts.stepIncreaseRatio, 'DisplayANFISInformation', 0,...
                'DisplayErrorValues', 0, 'DisplayStepSize', 0, 'DisplayFinalResults', 0,...
                'ValidationData', this_chk_data, 'OptimizationMethod', 1);
            [fis, trainingError , ~, ~, checkingError] = anfis(this_trn_data, anfisOpt);
            
        end
        
        %%Classification Tab functions
        function [imagePath, result] = loadsmearimage(app)
            %opens a file explorer window and gets file path for user selection.
            %returns smear image that should be analysed
            
            [file, path] = uigetfile({'*.jpg','*.jpeg'}, 'Select the smear image that should be analysed.');
            if isequal(file,0)
                %                 disp('User selected Cancel')
                imagePath = ' ';
                result = false;
            else
                %                 app.LoadedDataFilesTextArea.Value = sprintf('%s %s\n',strcat(app.LoadedDataFilesTextArea.Value{:}), file);
                imagePath = load(fullfile(path,file));
                app.classificationData.image.fFilePath = imagePath;
                result = true;
            end
        end
        

        function results = classify(app)
            %Does all calssification process using functions bellow.
            %.
            %.
            %. ___define "opratorAnalysisResults" property
            
            
            app.operatorAnalysisResults.region = [app.operatorAnalysisResults.region, app.singleRegionAnalysisResults.region];
            app.operatorAnalysisResults.softwareNumObjects = app.operatorAnalysisResults.softwareNumObjects + app.singleRegionAnalysisResults.numObjects;
            app.operatorAnalysisResults.softwareNumRBCs = app.operatorAnalysisResults.softwareNumRBCs + app.singleRegionAnalysisResults.numRBCs;
            app.operatorAnalysisResults.softwareNumFargments = app.operatorAnalysisResults.softwareNumFragments + app.singleRegionAnalysisResults.numFragments;
        end
        
        %_Image Processing functions
        %__preanalyse
        %__propertiese: "ANFIStargetImage" struct
        function c = rgb2cmyk(app,c)
            %%INTERNAL
            % RGB2CMYK   Converts between RGB- and CMYK- Colors
            %
            % RedGreenBlue <---> CyanMangentaYellowBlack
            %
            % CMYK = RGB2CMYK(  RGB )
            %
            %  RGB = RGB2CMYK( CMYK )
            %
            % RGB :  N by 3   , N Colors
            % CMYK:  N by 4
            %
            %
            applog(app,"H1: ""rgb2cmyk"" function called.");
            %             disp('H1: "rgb2cmyk" function called.')
            s = size(c);
            n = size(s,2);
            
            m = s(2+(n==3));
            
            if ~( isnumeric(c) & any( n == [ 2  3 ]) & ...
                    any( m == [ 3  4 ] ) )
                error('Input must be a RGB or CMYK ColorMatrice.');
            end
            
            if isempty(c)
                return
            end
            
            u8 = strcmp(class(c),'uint8');
            if u8
                c = double(c)/255;
            end
            
            sub = { ':'  ':' };
            sub = sub(1:(1+(n==3)));
            
            if m == 3
                %  RGB --> CMYK
                
                c = 1 - c;
                k = min(c,[],n);
                
                c = c - k(sub{:},[1 1 1]);
                
                c = cat( n , c , k );
                
            else
                % CMYK --> RGB
                
                c = 1 - ( c(sub{:},[1 2 3]) + c(sub{:},[4 4 4]) );
                
            end
            
            
            if u8
                c = uint8(round(c*255));
            end
        end
        
        function [mask,rgbMask] = bloodsmearbinarization(app, rgbImage,colorSpace, binarizationMethod, binarizationSensitivity, openingValue, closingValue, layerNum)
            %%INTERNAL
            % outputs binary image of the Cells (WBCs or RBCs+WBCs) in blood smear by defined method ( otsu,
            % graythresh or adaptive).
            % sensitivity for adaptive thresholding method is set to 0.9.
            % thershold level is retrived from rgb or gray scale image and performed on
            % s channel of hsv color space as this channel performes better on
            % segmentation of white blood cells.
            switch colorSpace
                case 'hsv'
                    hsvImage = rgb2hsv(rgbImage);
                    grayscaleImage = rgb2gray(rgbImage);
                    switch layerNum
                        case 'layer 1'
                            selected_channel = hsvImage(:,:,1);% h channel
                        case 'layer 2'
                            selected_channel = hsvImage(:,:,2);% s channel
                        case 'layer 3'
                            selected_channel = hsvImage(:,:,3);% v channel
                        case 'layer 4'
                            selected_channel = hsvImage(:,:,2);% s channel
                    end
                    selected_channel = imadjust(selected_channel);
                    switch binarizationMethod
                        case 'otsu'
                            [counts, x] = imhist(grayscaleImage, 32);
                            [level, em] = otsuthresh(counts);
                            binaryImage = im2bw(selected_channel, level);
                            binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                        case 'graythresh'
                            level = graythresh(rgbImage);
                            binaryImage = im2bw(selected_channel, level);
                            binaryImage = imerode(binaryImage, strel('disk', openingValue));
                            mask = imdilate(binaryImage, strel('disk', closingValue));
                            mask = imfill(mask);
                        case 'adaptive'
                            level = adaptthresh(selected_channel, binarizationSensitivity);
                            binaryImage = imbinarize(selected_channel, level);
                            binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                            mask = imfill(mask,'holes');
                        case 'manual'
                            % level = adaptthresh(selected_channel, binarizationSensitivity);
                            binaryImage = imbinarize(selected_channel, binarizationSensitivity);
                            %binaryImage = imopen(binaryImage, strel('disk', openingValue));
                            mask = imclose(binaryImage, strel('disk', closingValue));
                            %mask = bwareaopen(mask,200,4);
                            mask = imfill(mask,'holes');
                            mask = imerode(mask, strel('disk', openingValue));
                            %  mask = imdilate(mask, strel('disk', closingValue));
                            mask = bwareaopen(mask,100,4);%%%% modification needed: area size should be related to image size
                            mask = imfill(mask,'holes');
                            %mask = imopen(binaryImage, strel('disk', closingValue));
                    end
                    
                case 'cmyk'
                    cmykImage = app.rgb2cmyk(rgbImage);
                    y_channel = cmykImage(:,:,3);
                    %y_channel = y_channel .*2;
                    %y_channel = imadjust(y_channel,stretchlim(y_channel),[0,1]);
                    %y_channel = y_channel.*3;
                    %y_channelFilled = imfill(y_channel,26);
                    %y_channel = imdilate(imerode(y_channelFilled,strel('disk',1)),strel('disk',1));
                    %imshow(y_channel)
                    k_channel = cmykImage(:,:,4);
                    %k_channel = k_channel.*2;
                    %k_channel = imadjust(k_channel,stretchlim(k_channel),[0,1]);
                    k_channelFilled = imfill(k_channel);
                    k_channelADJ = imadjust(k_channelFilled,stretchlim(k_channelFilled),[0,1]);
                    switch binarizationMethod
                        case 'adaptive'
                            klevel = adaptthresh(k_channelADJ, binarizationSensitivity);
                            kMask = imbinarize(k_channelADJ, klevel);
                            mask = uint8(kMask);
                            yROI = y_channel .* mask;
                            yROI = imfill(yROI);
                            yROI = imadjust(imdilate(imerode(yROI,strel('disk',2)),strel('disk',3)));
                            level = adaptthresh(yROI, binarizationSensitivity);
                            binary = imbinarize(yROI, level);
                            binary = imdilate(binary, strel('disk',5));
                            binary = imfill(binary,'holes');
                            binary = imerode(binary,strel('disk',5));
                            k_channelMasked = k_channel;
                            k_channelMasked(imcomplement(binary)) = 0;
                            leveltest = adaptthresh(k_channelMasked,0.8);
                            bintest = imbinarize(k_channelMasked,leveltest);
                            bintest = imfill(bintest,'holes');
                            bintest = bwareaopen(bintest,1000,8);
                            k_channelMasked(imcomplement(bintest)) = 0;
                    end
            end
            rgbMask = rgbImage;
            rgbMask(repmat(~mask,[1 1 3])) = 0;
            clear hsvImage level selected_channel grayscaleImage
        end
        
        function preanalysimage(app, imageResizeRatio, RBCminSize, ANFIStargetImage)
            %%INTERNAL
            if ~exist('imageResizeRatio','var')
                imageResizeRatio = imResizeRatio; %default value for imageResizeRatio is 0.5
            end
            %I have used two stages of binarization to remove WBCs.
            %%%%%% modification needed: check image size and decide on size reduction and also bwareaopen input parameter.
            ANFIStargetImage.rgbImage = imresize(ANFIStargetImage.rgbImage,imageResizeRatio); %reducing image size in half
            [mask, rgbmask] = app.bloodsmearbinarization(ANFIStargetImage.rgbImage,...%finding WBCs (commented text are defaults)
                app.optionsvalues.wbccolorSpace,...%hsv
                app.optionsvalues.wbcbinarizationMethod,...%manual
                app.optionsvalues.wbcbinarizationSensitivity,...%0.98
                app.optionsvalues.wbcBWopeningValue,...%3
                app.optionsvalues.wbcBWclosingValue,...%2
                app.optionsvalues.wbccolorLayer); %layer 2
            mask = imdilate(mask,strel('disk',5));
            
            [mask2,rgbmask2] = app.bloodsmearbinarization(ANFIStargetImage.rgbImage,...%finding all objects including RBC and WBCs
                app.optionsvalues.rbccolorSpace,...%hsv
                app.optionsvalues.rbcbinarizationMethod,...%adaptive
                app.optionsvalues.rbcbinarizationSensitivity,...%0.9
                app.optionsvalues.rbcBWopeningValue,...%5
                app.optionsvalues.rbcBWclosingValue,...%1
                app.optionsvalues.rbccolorLayer);%layer 2
            
            %removing WBCs from the mask
            maskfinal = mask2 - mask;
            maskfinal = bwareaopen(maskfinal,RBCminSize,4); %The operator should be able to change the value 2000 or it should be define related to image size or microscope factor.
            ANFIStargetImage.mask = imclearborder(maskfinal,4);
            %END removing WBCs from the mask
            
            ANFIStargetImage.connectedComponents = bwconncomp(ANFIStargetImage.mask);
            % from here I use k channel of cmyk color space for RBC extraction.
            % Using H channel of HSV or G channel of RGB or even total grayscale image
            % should be investigated
            cmyk = app.rgb2cmyk(ANFIStargetImage.rgbImage);
            %Define type of 1D gray scale image to be masked for RBC counting (singe cell
            %segemntation)(This 1D image would be masked and ROIs in it would be analysed.?)
            ANFIStargetImage.oneDImage = cmyk(:,:,4); %k channel is choosed to be masked
            ANFIStargetImage.oneDImage = imadjust(ANFIStargetImage.oneDImage);
            % end of 1D gray scale image definition
        end   
    
        %__analyse
        function watershedAnalysisResults = singleregionsegmentor(app,rgbImage, oneDimage, cellMask, mainRegionID, houghSensitivity, houghCircleRange, rbcAreaThreshold)
            %singleregionsegmentor Summary of this function goes here
            %   I have developed the function by K channel of CMYK color space as
            %   "oneDimage". using other color spaces and channels should be examined.
            %   offsetType is Offset direction : offset0, offset45, offset90, offset135
            %   offsetNumber is number of points.
            %
            % ID of each final region is an array composed of [main region ID, subregion ID]. subregion ID can be rbc or fragment.
            % WatershedAnalysisResults =
            %   int             int         int                 array       struct
            % {'numObjects',0,'numRBCs',0,'numFragments',0,'fragmentIDs',[],'region',{'id',0,'mask',cellMask,'cell',regionSingle,'GLCMinput',false}}

            %% Defaults:
            if ~exist('houghSensitivity','var')
                houghSensitivity = 0.95; %default value for houghSensitivity
            end
            if ~exist('houghCircleRange','var')
                houghCircleRange = [20,40]; %default value for houghCircleRange
            end
            
            if ~exist('rbcAreaThreshold','var')
                rbcAreaThreshold = 2000; %default value for rbcAreaThreshold
            end
            numRBCs = 0;%????????
            %end Defaluts
            
            %% preparation

            regionSingle = im2double(oneDimage);

            regionSingle(~cellMask) = NaN; % masking whatever the oneDimage is (like CMYK channel K), by single region mask
            % cellMask is function input and is a parent function result that is the same size as rgbImage.
            
            regionStats = regionprops(cellMask, 'Area','BoundingBox', 'Centroid', 'Image', 'MajorAxisLength','MinorAxisLength'); %region properties of selected region that is detected in watershed analysis

            % cropping single region
            cropboxSize = regionStats.BoundingBox;
            cropBox = size(cropboxSize);
            cropBox(1) = regionStats.BoundingBox(1) -10;
            cropBox(2) = regionStats.BoundingBox(2) -10;
            cropBox(3) = regionStats.BoundingBox(3) +20;
            cropBox(4) = regionStats.BoundingBox(4) +20;
            %app.operatorAnalysisResults.region.position = cropBox;
            regionSinglezoomed = imcrop(regionSingle, cropBox); % This is oneDimage of the zoomed target region.
            cellMaskzoomed = imcrop(cellMask, cropBox); % cropping single region mask. cellMaskzoomed is zoomed mask of single region.
            
            %building rgb single region zoomed for visulization purpose
            rChannelregionSingleZoomedRGB = rgbImage(:,:,1);%its not zoomed yet
            rChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            rChannelregionSingleZoomedRGB = imcrop(rChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed
            
            gChannelregionSingleZoomedRGB = rgbImage(:,:,2); %its not zoomed yet
            gChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            gChannelregionSingleZoomedRGB = imcrop(gChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed
            
            bChannelregionSingleZoomedRGB = rgbImage(:,:,3); %its not zoomed yet
            bChannelregionSingleZoomedRGB(~cellMask) = NaN; %its not zoomed yet
            bChannelregionSingleZoomedRGB = imcrop(bChannelregionSingleZoomedRGB, cropBox);%now it's masked and zoomed

            regionSingleZoomedRGB = cat(3,rChannelregionSingleZoomedRGB, gChannelregionSingleZoomedRGB, bChannelregionSingleZoomedRGB);%zoomed rgb of one cell

%             %% Hough Transform
%             %calculating the hough circles areas in single region (single cell cluster)
%             
%             [centers, radii] = imfindcircles(regionSingle, houghCircleRange,'Sensitivity',houghSensitivity); % finding circles in single region houghSensitivity = 0.95
% 
%             [houghCentersRowN,houghCentersColN] = size(centers);   %count circle numbers by counting the centers
%             if houghCentersRowN ~= 0
%                 houghArea = zeros(size(radii));
%                 for circlei =  1:houghCentersRowN
%                     houghArea(circlei) = pi*((radii(circlei))^2);%calculate the area by pi*r^2 formula
%                 end
%             else
%                 houghArea = 0;
%             end
%             
            %% Watershed Analysis
            % This can be a seperate function.
            %define empty containers

            watershedAnalysisResults = struct(  'numObjects',0,...      any subregion
                'numRBCs',0,...         any subregion that is larger than 'rbcAreaThreshold'
                'numFragments',0,...    any sub-region (object) that is smaller than 'rbcAreaThreshold'
                'fragmentIDs',[],...    ID of fregment including the main region ID  'fragmentIDs',[],...    ID of fregment including the main region ID
                'region',struct('regionID',uint16([mainRegionID, 00]),...
                'position',cropBox,...
                'mask',cellMaskzoomed,... main single region mask is used as defualt value
                'cell',regionSinglezoomed,... main single region masked image is used as default value
                'cellRGB',regionSingleZoomedRGB,...
                'isRBC',false,...
                'GLCM', [],...
                'operatorAnalysis', 1,...
                'operatorCellQt',1));
            % in this struct, "fragment" means any sub-region (object) that is smaller than 'rbcAreaThreshold'
            %ID = 0 means that watershed algorithm did not recogonized any regions.
            
            fragmentsTotalArea = 0;
            wsrChannelregionSingleZoomedRGB = rChannelregionSingleZoomedRGB;
            wsgChannelregionSingleZoomedRGB = gChannelregionSingleZoomedRGB;
            wsbChannelregionSingleZoomedRGB = bChannelregionSingleZoomedRGB;
            % end define empty containers

            wsbinarry = cellclusterwatershed(cellMaskzoomed); % applying Watershed algorithm on binarry image of single region
            cc = bwconncomp(wsbinarry);
            NumWatershedRegions = cc.NumObjects;
            WatershedRegionsStats = regionprops(cc, 'Area');

            %assessing watershed regions
            if isequal(NumWatershedRegions, 0)|| NumWatershedRegions==1
                watershedAnalysisResults.numObjects = 1;
                watershedAnalysisResults.numRBCs = 1; %Watershed algorithm did not recogonized any regions so there should be at least one RBC, because it was a region from final mask.
                watershedAnalysisResults.numFragments = 0;
                watershedAnalysisResults.region.regionID = uint16([mainRegionID, 0]);%ID = 0 means that watershed algorithm did not recogonized any regions.
                watershedAnalysisResults.region.mask = cellMaskzoomed;
                watershedAnalysisResults.region.cell = regionSinglezoomed;
                watershedAnalysisResults.region.isRBC = true;
                watershedAnalysisResults.region.position = cropBox*(1/app.imResizeRatio);
            else
                for wscelli = 1:NumWatershedRegions   %investigate each watershed region
                    wscellMask = false(size(cellMaskzoomed)); % building a mask the same size as single region image

                    wscellMask(cc.PixelIdxList{wscelli}) = true;% building watershed single cell mask
                    
                    wscellMask = imdilate(wscellMask,strel('disk',3)); % dilating the mask to preserve cell image data
                    wsregionSingleZoomed = regionSinglezoomed;% defining base single cell image
                    wsregionSingleZoomed(~wscellMask) = NaN; % extracting watershed single cell.
                    
                    %masking watershed single cell result in rgb image
                    wsrChannelregionSingleZoomedRGB = rChannelregionSingleZoomedRGB;
                    wsgChannelregionSingleZoomedRGB = gChannelregionSingleZoomedRGB;
                    wsbChannelregionSingleZoomedRGB = bChannelregionSingleZoomedRGB;
                    wsrChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsgChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsbChannelregionSingleZoomedRGB(~wscellMask) = NaN;
                    wsRegionSingleZoomedRGB = regionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,1) = wsrChannelregionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,2) = wsgChannelregionSingleZoomedRGB;
                    wsRegionSingleZoomedRGB(:,:,3) = wsbChannelregionSingleZoomedRGB;
                    
                    %end of masking rgb image
                    %start test
                    imshow(wsRegionSingleZoomedRGB, 'parent', app.CellUIAxes);
                    %                     w = waitforbuttonpress;
                    %end test
                    %start test
                    %watershedAnalysisResults.region(wscelli).regionID(2) = wscelli;
                    watershedAnalysisResults.region(wscelli).regionID = uint16([mainRegionID, wscelli]);
                    %end test
                    watershedAnalysisResults.region(wscelli).mask = wscellMask;
                    watershedAnalysisResults.region(wscelli).cell = wsregionSingleZoomed;
                    watershedAnalysisResults.region(wscelli).cellRGB = wsRegionSingleZoomedRGB;
                    watershedAnalysisResults.region(wscelli).isRBC = true;
                    watershedAnalysisResults.region(wscelli).operatorAnalysis = 1;
                    watershedAnalysisResults.region(wscelli).operatorCellQt = 1;
                    watershedAnalysisResults.region(wscelli).position = cropBox*(1/app.imResizeRatio);

                    if WatershedRegionsStats(wscelli).Area < rbcAreaThreshold
                        watershedAnalysisResults.numFragments = watershedAnalysisResults.numFragments + 1;
                        watershedAnalysisResults.fragmentIDs = [watershedAnalysisResults.fragmentIDs,wscelli]; % fragment IDs are stored to be used in sum of fragment areas
                        watershedAnalysisResults.region(wscelli).isRBC = false; %fragments are not suitable for GLCM calculation
                    end
                end
                %calculating quantity of each type of object.
                watershedAnalysisResults.numObjects = NumWatershedRegions;
                watershedAnalysisResults.numRBCs = watershedAnalysisResults.numObjects - watershedAnalysisResults.numFragments;
                
                
                if watershedAnalysisResults.numFragments ~=0
                    for fragmenti = 1:length(watershedAnalysisResults.fragmentIDs)
                        fragmentsTotalArea = fragmentsTotalArea + WatershedRegionsStats(watershedAnalysisResults.fragmentIDs(fragmenti)).Area; %calculate total fragments area.
                    end
                end
                
                % In line below integer division of total fragments areas are added
                % to rbc count. maybe it is better to add just number one.
                % !!!!!CHECK!!!!!
                watershedAnalysisResults.numRBCs = watershedAnalysisResults.numRBCs + idivide(int32(fragmentsTotalArea),int32(rbcAreaThreshold));
                %END OF calculating quantity of each type of object.
            end
        end
    
        
        function singleRegionAnalysisResults = analysproperties(app,offsetNumber, offsetType)%currentRegion, numOfRegions
            %calculates GLCM
            %inputs: GLCM offsetNumber, GLCM offsetType, app.ANFIStargetImage, app.currentRegion, app.numOfRegions
            % app.singleregionsegmentor() function is used here.
            %output: singleRegionAnalysisResults

            %defaults
            if ~exist('offsetNumber','var')
                offsetNumber = 40; %default value for rbcAreaThreshold
            end
            switch offsetType
                case 'omni'
                    glcmOffset = [0 offsetNumber;-offsetNumber offsetNumber;-offsetNumber 0;-offsetNumber -offsetNumber];
                case 'offset0'
                    glcmOffset = [zeros(offsetNumber,1) (1:offsetNumber)'];
                case 'offset45'
                    glcmOffset = [-(1:1:offsetNumber)' (1:1:offsetNumber)'];
                case 'offset90'
                    glcmOffset = [-(1:1:offsetNumber)' zeros(offsetNumber,1)];
                case 'offset135'
                    glcmOffset = [-(1:1:offsetNumber)' -(1:1:offsetNumber)'];
                otherwise
                    glcmOffset = [-(1:offsetNumber)' (1:1:offsetNumber)']; % if the offset type is not defined correctly, offset45 is considered
            end
            
            singleRegionAnalysisResults = struct( 'numObjects',0,...      any subregion
                'numRBCs',0,...         any subregion that is larger than 'rbcAreaThreshold'
                'numFragments',0,...    any sub-region (object) that is smaller than 'rbcAreaThreshold'
                'fragmentIDs',[],...    ID of fregment including the main region ID
                'region',struct('regionID',uint16([00, 00]),...
                'position',[],...
                'mask',0,... main single region mask is used as defualt value
                'cell',0,... main single region masked image is used as default value
                'cellRGB',0,...
                'isRBC',true,...
                'GLCM', [],...
                'operatorAnalysis', 1,...
                'operatorCellQt', 1));
            %watershed for each detected region.(regions are detected by concomp())
            for regionNum = 1:app.ANFIStargetImage.connectedComponents.NumObjects   %from now on regionNum will be cell or cell cluster ID.
                %for cells exctracted from cell clusters a second ID will be generated.
                app.currentRegion.currentCellMask = false(size(app.ANFIStargetImage.mask)); % building a mask the same size as image
                app.currentRegion.currentCellMask(app.ANFIStargetImage.connectedComponents.PixelIdxList{regionNum}) = true;% building single region mask
                app.numOfRegions = app.ANFIStargetImage.connectedComponents.NumObjects;
                %%%%%%%%%%%%%%% imshow(app.targetImage.oneDImage,'parent',app.CellUIAxes);
                app.currentRegion.regionID(1) = regionNum;
                singleRegionAnalysisResults = app.singleregionsegmentor(app.ANFIStargetImage.rgbImage, app.ANFIStargetImage.oneDImage, app.currentRegion.currentCellMask, app.currentRegion.regionID(1));

                for regioni = 1 : singleRegionAnalysisResults.numObjects
                    app.currentRegion.regionID(2) = regioni;
                    if singleRegionAnalysisResults.region(regioni).isRBC
                        glcms = graycomatrix(app.singleRegionAnalysisResults.region(regioni).cell, 'offset', glcmOffset);
                        glcmStats = graycoprops(glcms,'all');
                        singleRegionAnalysisResults.region(regioni).GLCM = glcmStats;
                    end
                end

            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             warnStruct = warning('off','images:graycomatrix:scaledImageContainsNan');
            %             warning(warnStruct);
            warning('off');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [loadedData, result] = app.loadoperatoranalysis();
            if ~result
                app.LoadedDataFilesTextArea.Value = sprintf('%s >>%s\n', strcat(app.LoadedDataFilesTextArea.Value{:}), 'user selected "Cancel".');
            else
                app.processanalysedsmeardata(loadedData);
            end
        end

        % Button pushed function: InsertDataButton
        function InsertDataButtonPushed(app, event)
            app.readdesigndatatab();
            [app.trainingDataTotal, app.checkingDataTotal] = app.inserttrainingcheckingdata();
            app.LoadedDataFilesTextArea.Value = sprintf('%s\n >>unknown: %d Normal: %d Target: %d Spherocyte: %d Hypo: %d Hyper: %d\n', strcat(app.LoadedDataFilesTextArea.Value{:}), app.softwareTempVariables.processedLoadedDataNumber.unknown,...
                app.softwareTempVariables.processedLoadedDataNumber.normal, app.softwareTempVariables.processedLoadedDataNumber.target,...
                app.softwareTempVariables.processedLoadedDataNumber.spherocyte, app.softwareTempVariables.processedLoadedDataNumber.hypo,...
                app.softwareTempVariables.processedLoadedDataNumber.hyper);
        end

        % Button pushed function: SettingsSaveButton
        function SettingsSaveButtonPushed(app, event)
            if app.readsettingstab()
                if app.savevariable(app.softwareTempVariables.settingsTab, 'settings')
                    app.fillinputnames(app.softwareTempVariables.settingsTab.anfis.inputs,app.trainingDataTotal.fisLayer.variableNames.inputs);
                    msgbox('saved successfully','save function');
                end
            else
                msgbox('Error in retrieving data.','save function', 'error');
            end
            
        end

        % Button pushed function: SaveTrainingDataButton
        function SaveTrainingDataButtonPushed(app, event)
            if app.savevariable(app.trainingDataTotal, 'TrainingData')
                msgbox('Training Data saved successfully.','save function');
            end
        end

        % Button pushed function: SaveCheckingDataButton
        function SaveCheckingDataButtonPushed(app, event)
            if app.savevariable(app.trainingDataTotal, 'CheckingData')
                msgbox('Check Data saved successfully.','save function');
            end
        end

        % Button pushed function: TrainCheckANFISButton
        function TrainCheckANFISButtonPushed(app, event)
            
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: SaveReportButton
        function SaveReportButtonPushed2(app, event)
            [fileName,path] = uiputfile('*.docx','save input search report ','ExhaustiveSearchForInputsReport');
            if app.reportinputsearchresults(app.inputSearchReportData, app.trainingDataTotal.fisLayer.variableNames.inputs, "Arash Nedaei", fullfile(path,fileName))
                msgbox('Input Search Report saved successfully.','save report');
            end
        end

        % Button pushed function: InputSearchButton
        function InputSearchButtonPushed(app, event)
            app.readinputsearchtab();
            app.inputSearchReportData = app.exhaustiveinputsearch(app.trainingDataTotal, app.checkingDataTotal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SETTINGSTab = uitab(app.TabGroup);
            app.SETTINGSTab.Title = 'SETTINGS';
            app.IMAGESETTINGSPanel = uipanel(app.SETTINGSTab);
            app.IMAGESETTINGSPanel.Title = 'IMAGE SETTINGS';
            app.ColorcodeDropDownLabel = uilabel(app.IMAGESETTINGSPanel);
            app.ColorcodeDropDownLabel.Text = 'Color code';
            app.ColorcodeDropDown = uidropdown(app.IMAGESETTINGSPanel);
            app.ColorcodeDropDown.Items = {'RGB', 'CMYK', 'Option 3', 'Option 4'};
            app.ColorcodeDropDown.Value = 'RGB';
            app.ColorLayerDropDownLabel = uilabel(app.IMAGESETTINGSPanel);
            app.ColorLayerDropDownLabel.Text = 'Color Layer';
            app.ColorLayerDropDown = uidropdown(app.IMAGESETTINGSPanel);
            app.GLCMSETTINGSPanel = uipanel(app.SETTINGSTab);
            app.GLCMSETTINGSPanel.Title = 'GLCM SETTINGS';
            app.OffsetNumberEditFieldLabel = uilabel(app.GLCMSETTINGSPanel);
            app.OffsetNumberEditFieldLabel.Text = 'Offset Number';
            app.OffsetNumberEditField = uieditfield(app.GLCMSETTINGSPanel, 'numeric');
            app.OffsetNumberEditField.Limits = [1 100];
            app.OffsetNumberEditField.Value = 4;
            app.OffsetDirectionButtonGroup = uibuttongroup(app.GLCMSETTINGSPanel);
            app.OffsetDirectionButtonGroup.Title = 'Offset Direction';
            app.omniButton = uiradiobutton(app.OffsetDirectionButtonGroup);
            app.omniButton.Text = 'omni';
            app.omniButton.Value = true;
            app.offset0Button = uiradiobutton(app.OffsetDirectionButtonGroup);
            app.offset0Button.Text = 'offset0';
            app.offset45Button = uiradiobutton(app.OffsetDirectionButtonGroup);
            app.offset45Button.Text = 'offset45';
            app.offset90Button = uiradiobutton(app.OffsetDirectionButtonGroup);
            app.offset90Button.Text = 'offset90';
            app.offset135Button = uiradiobutton(app.OffsetDirectionButtonGroup);
            app.offset135Button.Text = 'offset135';
            app.ANFISOPTIONSPanel = uipanel(app.SETTINGSTab);
            app.ANFISOPTIONSPanel.Title = 'ANFIS OPTIONS';
            app.MembershipfunctionDropDownLabel = uilabel(app.ANFISOPTIONSPanel);
            app.MembershipfunctionDropDownLabel.Text = 'Membership function';
            app.MembershipfunctionDropDown = uidropdown(app.ANFISOPTIONSPanel);
            app.MembershipfunctionDropDown.Items = {'gbellmf', 'gaussmf', 'gauss2mf', 'trimf', 'trapmf', 'sigmf', 'dsigmf', 'psigmf', 'zmf', 'pimf', 'smf'};
            app.MembershipfunctionDropDown.Value = 'gbellmf';
            app.StepsizeEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.StepsizeEditField.Limits = [0.001 1];
            app.StepsizeEditField.Value = 0.1;
            app.StepdecreaseratioEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.StepdecreaseratioEditFieldLabel.Text = 'Step decrease ratio';
            app.StepdecreaseratioEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.StepdecreaseratioEditField.Limits = [0.01 10];
            app.StepdecreaseratioEditField.Value = 0.5;
            app.StepincreaseratioEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.StepincreaseratioEditFieldLabel.Text = 'Step increase ratio';
            app.StepincreaseratioEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.StepincreaseratioEditField.Limits = [0.1 10];
            app.StepincreaseratioEditField.Value = 1.5;
            app.ClusteringmethodDropDownLabel = uilabel(app.ANFISOPTIONSPanel);
            app.ClusteringmethodDropDownLabel.Text = 'Clustering method';
            app.ClusteringmethodDropDown = uidropdown(app.ANFISOPTIONSPanel);
            app.ClusteringmethodDropDown.Items = {'GridPartition', 'SubtractiveClustering', 'FCMClustering'};
            app.ClusteringmethodDropDown.Value = 'GridPartition';
            app.EpochNumberEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.EpochNumberEditFieldLabel.Text = 'Epoch Number';
            app.EpochNumberEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.EpochNumberEditField.Limits = [1 1000];
            app.EpochNumberEditField.Value = 100;
            app.INPUTSLabel = uilabel(app.ANFISOPTIONSPanel);
            app.INPUTSLabel.Tooltip = {'{''contrast1'''; '''contrast2'''; '''contrast3'''; '''contrast4'''; '''correlation1'''; '''correlation2'''; '''correlation3'''; '''correlation4'''; '''energy1'''; '''energy2'''; '''energy3'''; '''energy4'''; '''homogeneity1'''; '''homogeneity2'''; '''homogeneity3'''; '''homogeneity4'''; '''contrastMean'''; '''correlationMean'''; '''energyMean'''; '''homogeneityMean''}'};
            app.INPUTSLabel.Text = 'INPUTS';
            app.cellTypeFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.cellTypeFISEditFieldLabel.Text = 'cellType FIS';
            app.cellTypeFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.abnormalityFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.abnormalityFISEditFieldLabel.Text = 'abnormality FIS';
            app.abnormalityFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.targetFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.targetFISEditFieldLabel.Text = 'target FIS';
            app.targetFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.spherocyteFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.spherocyteFISEditFieldLabel.Text = 'spherocyte FIS';
            app.spherocyteFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.hypoFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.hypoFISEditFieldLabel.Text = 'hypo FIS';
            app.hypoFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.hyperFISEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.hyperFISEditFieldLabel.Text = 'hyper FIS';
            app.hyperFISinputsEditField = uieditfield(app.ANFISOPTIONSPanel, 'text');
            app.cellTypeOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.cellTypeOTEditFieldLabel.Text = 'cellType OT';
            app.cellTypeOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.cellTypeOTEditField.Value = 0.5;
            app.OUTPUTSTHRESHOLDLabel = uilabel(app.ANFISOPTIONSPanel);
            app.OUTPUTSTHRESHOLDLabel.Text = 'OUTPUTS THRESHOLD';
            app.abnormalityOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.abnormalityOTEditFieldLabel.Text = 'abnormality OT';
            app.abnormalityOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.abnormalityOTEditField.Value = 0.5;
            app.targetOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.targetOTEditFieldLabel.Text = 'target OT';
            app.targetOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.targetOTEditField.Value = 0.5;
            app.spherocyteOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.spherocyteOTEditFieldLabel.Text = 'spherocyte OT';
            app.spherocyteOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.spherocyteOTEditField.Value = 0.5;
            app.hypoOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.hypoOTEditFieldLabel.Text = 'hypo OT';
            app.hypoOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.hypoOTEditField.Value = 0.5;
            app.hyperOTEditFieldLabel = uilabel(app.ANFISOPTIONSPanel);
            app.hyperOTEditFieldLabel.Text = 'hyper OT';
            app.hyperOTEditField = uieditfield(app.ANFISOPTIONSPanel, 'numeric');
            app.hyperOTEditField.Value = 0.5;
            app.ProfileNameEditFieldLabel = uilabel(app.SETTINGSTab);
            app.ProfileNameEditFieldLabel.Text = 'Profile Name';
            app.SettingsProfileNameEditField = uieditfield(app.SETTINGSTab, 'text');
            app.SettingsLoadButton = uibutton(app.SETTINGSTab, 'push');
            app.SettingsLoadButton.Text = 'Load settings';
            app.SettingsSaveButton = uibutton(app.SETTINGSTab, 'push');
            app.SettingsSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsSaveButtonPushed, true);
            app.SettingsSaveButton.Text = 'Save settings';
            app.DESIGNDATATab = uitab(app.TabGroup);
            app.DESIGNDATATab.Title = 'DESIGN DATA';
            app.TrainingDataPanel = uipanel(app.DESIGNDATATab);
            app.TrainingDataPanel.Title = 'Training Data';
            app.SaveTrainingDataButton = uibutton(app.TrainingDataPanel, 'push');
            app.SaveTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrainingDataButtonPushed, true);
            app.SaveTrainingDataButton.Text = 'Save Training Data';
            app.LoadTrainingDataButton = uibutton(app.TrainingDataPanel, 'push');
            app.LoadTrainingDataButton.Text = 'Load Training Data';
            app.UnknownEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.UnknownEditFieldLabel.Text = 'Unknown';
            app.TrainingUnknownNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingUnknownNumber.Limits = [10 1000];
            app.TrainingUnknownNumber.Value = 10;
            app.NormalEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.NormalEditFieldLabel.Text = 'Normal';
            app.TrainingNormalNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingNormalNumber.Limits = [10 1000];
            app.TrainingNormalNumber.Value = 10;
            app.TargetEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TrainingTargetNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingTargetNumber.Limits = [10 1000];
            app.TrainingTargetNumber.Value = 10;
            app.SpherocyteEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.SpherocyteEditFieldLabel.Text = 'Spherocyte';
            app.TrainingSpherocyteNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingSpherocyteNumber.Limits = [10 1000];
            app.TrainingSpherocyteNumber.Value = 10;
            app.HypoEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.HypoEditFieldLabel.Text = 'Hypo';
            app.TrainingHypoNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingHypoNumber.Limits = [10 1000];
            app.TrainingHypoNumber.Value = 10;
            app.HyperEditFieldLabel = uilabel(app.TrainingDataPanel);
            app.HyperEditFieldLabel.Text = 'Hyper';
            app.TrainingHyperNumber = uieditfield(app.TrainingDataPanel, 'numeric');
            app.TrainingHyperNumber.Limits = [10 1000];
            app.TrainingHyperNumber.Value = 10;
            app.NumberofcellclassesforANFIStrainingLabel = uilabel(app.TrainingDataPanel);
            app.NumberofcellclassesforANFIStrainingLabel.Text = 'Number of cell classes for ANFIS training';
            app.CheckingDataPanel = uipanel(app.DESIGNDATATab);
            app.CheckingDataPanel.Title = 'Checking Data';
            app.LoadCheckingDataButton = uibutton(app.CheckingDataPanel, 'push');
            app.LoadCheckingDataButton.Text = 'Load Checking Data';
            app.SaveCheckingDataButton = uibutton(app.CheckingDataPanel, 'push');
            app.SaveCheckingDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCheckingDataButtonPushed, true);
            app.SaveCheckingDataButton.Text = 'Save Checking Data';
            app.UnknownEditField_2Label = uilabel(app.CheckingDataPanel);
            app.UnknownEditField_2Label.Text = 'Unknown';
            app.CheckingUnknownNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingUnknownNumber.Limits = [0 1000];
            app.CheckingUnknownNumber.Value = 10;
            app.NormalEditField_2Label = uilabel(app.CheckingDataPanel);
            app.NormalEditField_2Label.Text = 'Normal';
            app.CheckingNormalNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingNormalNumber.Limits = [0 1000];
            app.CheckingNormalNumber.Value = 10;
            app.TargetEditField_2Label = uilabel(app.CheckingDataPanel);
            app.TargetEditField_2Label.Text = 'Target';
            app.CheckingTargetNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingTargetNumber.Limits = [0 1000];
            app.CheckingTargetNumber.Value = 10;
            app.SpherocyteEditField_2Label = uilabel(app.CheckingDataPanel);
            app.SpherocyteEditField_2Label.Text = 'Spherocyte';
            app.CheckingSpherocyteNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingSpherocyteNumber.Limits = [0 1000];
            app.CheckingSpherocyteNumber.Value = 10;
            app.HypoEditField_2Label = uilabel(app.CheckingDataPanel);
            app.HypoEditField_2Label.Text = 'Hypo';
            app.CheckingHypoNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingHypoNumber.Limits = [0 1000];
            app.CheckingHypoNumber.Value = 10;
            app.HyperEditField_2Label = uilabel(app.CheckingDataPanel);
            app.HyperEditField_2Label.Text = 'Hyper';
            app.CheckingHyperNumber = uieditfield(app.CheckingDataPanel, 'numeric');
            app.CheckingHyperNumber.Limits = [0 1000];
            app.CheckingHyperNumber.Value = 10;
            app.NumberofcellclassesforANFIScheckingLabel = uilabel(app.CheckingDataPanel);
            app.NumberofcellclassesforANFIScheckingLabel.Text = 'Number of cell classes for ANFIS checking';
            app.LoadDataButton = uibutton(app.DESIGNDATATab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadedDataFilesTextAreaLabel = uilabel(app.DESIGNDATATab);
            app.LoadedDataFilesTextAreaLabel.Text = 'Loaded Data Files';
            app.LoadedDataFilesTextArea = uitextarea(app.DESIGNDATATab);
            app.LoadedDataFilesTextArea.Editable = 'off';
            app.InsertDataButton = uibutton(app.DESIGNDATATab, 'push');
            app.InsertDataButton.ButtonPushedFcn = createCallbackFcn(app, @InsertDataButtonPushed, true);
            app.InsertDataButton.Text = 'Insert Data ->';
            app.INPUTSEARCHTab = uitab(app.TabGroup);
            app.INPUTSEARCHTab.Title = 'INPUT SEARCH';
            app.SaveReportButton = uibutton(app.INPUTSEARCHTab, 'push');
            app.SaveReportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReportButtonPushed2, true);
            app.SaveReportButton.Enable = 'off';
            app.SaveReportButton.Text = 'Save Report';
            app.InputSearchButton = uibutton(app.INPUTSEARCHTab, 'push');
            app.InputSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputSearchButtonPushed, true);
            app.InputSearchButton.Text = 'Input Search';
            app.cellTypeLampLabel = uilabel(app.INPUTSEARCHTab);
            app.cellTypeLampLabel.Text = 'cellType';
            app.cellTypeLamp = uilamp(app.INPUTSEARCHTab);
            app.abnormalityLampLabel = uilabel(app.INPUTSEARCHTab);
            app.abnormalityLampLabel.Text = 'abnormality';
            app.abnormalityLamp = uilamp(app.INPUTSEARCHTab);
            app.targetLampLabel = uilabel(app.INPUTSEARCHTab);
            app.targetLampLabel.Text = 'target';
            app.targetLamp = uilamp(app.INPUTSEARCHTab);
            app.spherocyteLampLabel = uilabel(app.INPUTSEARCHTab);
            app.spherocyteLampLabel.Text = 'spherocyte';
            app.spherocyteLamp = uilamp(app.INPUTSEARCHTab);
            app.hypoLampLabel = uilabel(app.INPUTSEARCHTab);
            app.hypoLampLabel.Text = 'hypo';
            app.hypoLamp = uilamp(app.INPUTSEARCHTab);
            app.hyperLampLabel = uilabel(app.INPUTSEARCHTab);
            app.hyperLampLabel.Text = 'hyper';
            app.hyperLamp = uilamp(app.INPUTSEARCHTab);
            app.InputSearchEpochNumberLabel = uilabel(app.INPUTSEARCHTab);
            app.InputSearchEpochNumberLabel.Text = 'Input Search Epoch Number';
            app.inputSearchEpochNumberEditField = uieditfield(app.INPUTSEARCHTab, 'numeric');
            app.inputSearchEpochNumberEditField.Value = 1;
            app.ANFISDESIGNRESULTSTab = uitab(app.TabGroup);
            app.ANFISDESIGNRESULTSTab.Title = 'ANFIS DESIGN RESULTS';
            app.UIAxes = uiaxes(app.ANFISDESIGNRESULTSTab);
            title(app.UIAxes, 'Error')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.ANFISDESIGNRESULTSTab);
            title(app.UIAxes2, 'FIS Diagram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.TrainCheckANFISButton = uibutton(app.ANFISDESIGNRESULTSTab, 'push');
            app.TrainCheckANFISButton.ButtonPushedFcn = createCallbackFcn(app, @TrainCheckANFISButtonPushed, true);
            app.TrainCheckANFISButton.Text = 'Train & Check ANFIS';
            app.ANFISoutputdataTextAreaLabel = uilabel(app.ANFISDESIGNRESULTSTab);
            app.ANFISoutputdataTextAreaLabel.Text = 'ANFIS output data';
            app.ANFISoutputdataTextArea = uitextarea(app.ANFISDESIGNRESULTSTab);
            app.savedesignButton = uibutton(app.ANFISDESIGNRESULTSTab, 'push');
            app.savedesignButton.Text = 'save design';
            app.loaddesignButton = uibutton(app.ANFISDESIGNRESULTSTab, 'push');
            app.loaddesignButton.Text = 'load design';
            app.CLASSIFICATIONTab = uitab(app.TabGroup);
            app.CLASSIFICATIONTab.Title = 'CLASSIFICATION';
            app.UIAxes3 = uiaxes(app.CLASSIFICATIONTab);
            title(app.UIAxes3, 'Smear image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.CLASSIFICATIONTab);
            title(app.UIAxes4, 'Cell image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.LoadSmearImageButton = uibutton(app.CLASSIFICATIONTab, 'push');
            app.LoadSmearImageButton.Text = 'Load Smear Image';
            app.AnalysisResultsPanel = uipanel(app.CLASSIFICATIONTab);
            app.AnalysisResultsPanel.Title = 'Analysis Results';
            app.Button2 = uibutton(app.CLASSIFICATIONTab, 'push');
            app.Button2.Text = 'Button2';
            app.previousButton = uibutton(app.CLASSIFICATIONTab, 'push');
            app.previousButton.Text = 'previous';
            app.nextButton = uibutton(app.CLASSIFICATIONTab, 'push');
            app.nextButton.Text = 'next';
            app.AnalysisResultEditFieldLabel = uilabel(app.CLASSIFICATIONTab);
            app.AnalysisResultEditFieldLabel.Text = 'Analysis Result';
            app.AnalysisResultEditField = uieditfield(app.CLASSIFICATIONTab, 'text');
            app.ClassifyButton = uibutton(app.CLASSIFICATIONTab, 'push');
            app.ClassifyButton.Text = 'Classify';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RBC_ANFIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end